classdef DATCOM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlCardsTab                 matlab.ui.container.Tab
        DimensionsUnitButtonGroup       matlab.ui.container.ButtonGroup
        ImperialButton                  matlab.ui.control.RadioButton
        MetricButton                    matlab.ui.control.RadioButton
        DerivativesUnitButtonGroup      matlab.ui.container.ButtonGroup
        DegreeButton                    matlab.ui.control.RadioButton
        RadianButton                    matlab.ui.control.RadioButton
        OtherOptionsPanel               matlab.ui.container.Panel
        DynamicDerivativesadvicemakeitonCheckBox  matlab.ui.control.CheckBox
        PartmoduleCheckBox              matlab.ui.control.CheckBox
        BuildmoduleCheckBox             matlab.ui.control.CheckBox
        SaveControlCardsDataButton      matlab.ui.control.Button
        FlightConditionsTab             matlab.ui.container.Tab
        TakeoffWeightEditFieldLabel     matlab.ui.control.Label
        TakeoffWeightEditField          matlab.ui.control.NumericEditField
        MinMachNumberEditFieldLabel     matlab.ui.control.Label
        MinMachNumberEditField          matlab.ui.control.NumericEditField
        MaxMachNumberEditFieldLabel     matlab.ui.control.Label
        MaxMachNumberEditField          matlab.ui.control.NumericEditField
        NumberofMachnumbersMAX20Label   matlab.ui.control.Label
        NumberofMachnumbersMAX20EditField  matlab.ui.control.NumericEditField
        MinAltitudesEditFieldLabel      matlab.ui.control.Label
        MinAltitudesEditField           matlab.ui.control.NumericEditField
        MaxAltitudesEditFieldLabel      matlab.ui.control.Label
        MaxAltitudesEditField           matlab.ui.control.NumericEditField
        NumberofAltitudesMAX20EditFieldLabel  matlab.ui.control.Label
        NumberofAltitudesMAX20EditField  matlab.ui.control.NumericEditField
        MinAngleofAttacksEditFieldLabel  matlab.ui.control.Label
        MinAngleofAttacksEditField      matlab.ui.control.NumericEditField
        MaxAngleofAttacksEditFieldLabel  matlab.ui.control.Label
        MaxAngleofAttacksEditField      matlab.ui.control.NumericEditField
        NumberofAngleofAttacksMAX20EditFieldLabel  matlab.ui.control.Label
        NumberofAngleofAttacksMAX20EditField  matlab.ui.control.NumericEditField
        ProgramLoopingControlButtonGroup  matlab.ui.container.ButtonGroup
        VaryaltitudeandMachtogetherButton  matlab.ui.control.RadioButton
        VaryMachatfixedaltitudeButton   matlab.ui.control.RadioButton
        VaryaltitudeatfixedMachButton   matlab.ui.control.RadioButton
        FlightConditionsSaveDataButton  matlab.ui.control.StateButton
        OptionsTab                      matlab.ui.container.Tab
        ReferenceAreaEditFieldLabel     matlab.ui.control.Label
        ReferenceAreaEditField          matlab.ui.control.NumericEditField
        MeanAerodynamcChordEditFieldLabel  matlab.ui.control.Label
        MeanAerodynamcChordEditField    matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        SurfaceRoughnessEditFieldLabel  matlab.ui.control.Label
        SurfaceRoughnessEditField       matlab.ui.control.NumericEditField
        SaveOptionsDataButton           matlab.ui.control.Button
        SynthesisTab                    matlab.ui.container.Tab
        SaveSynthesisDataButton         matlab.ui.control.StateButton
        LongitudinalLocationofCGLabel   matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_2  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_3  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_4  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_5  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_6  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_7  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_8  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_9  matlab.ui.control.Label
        XCGEditFieldLabel               matlab.ui.control.Label
        XCGEditField                    matlab.ui.control.NumericEditField
        ZWEditFieldLabel                matlab.ui.control.Label
        ZWEditField                     matlab.ui.control.NumericEditField
        XWLabel                         matlab.ui.control.Label
        XWEditField                     matlab.ui.control.NumericEditField
        ALIWEditFieldLabel              matlab.ui.control.Label
        ALIWEditField                   matlab.ui.control.NumericEditField
        ALIHEditFieldLabel              matlab.ui.control.Label
        ALIHEditField                   matlab.ui.control.NumericEditField
        ZHLabel                         matlab.ui.control.Label
        ZHEditField                     matlab.ui.control.NumericEditField
        XHLabel                         matlab.ui.control.Label
        XHEditField                     matlab.ui.control.NumericEditField
        XVLabel                         matlab.ui.control.Label
        XVEditField                     matlab.ui.control.NumericEditField
        ZVLabel                         matlab.ui.control.Label
        ZVEditField                     matlab.ui.control.NumericEditField
        SCALELabel                      matlab.ui.control.Label
        SCALEEditField                  matlab.ui.control.NumericEditField
        ZCGEditField                    matlab.ui.control.NumericEditField
        ZCGEditFieldLabel               matlab.ui.control.Label
        WingTab                         matlab.ui.container.Tab
        CHRDTPEditFieldLabel            matlab.ui.control.Label
        CHRDTPEditField                 matlab.ui.control.NumericEditField
        SSPNEditFieldLabel              matlab.ui.control.Label
        SSPNEditField                   matlab.ui.control.NumericEditField
        SSPNEEditFieldLabel             matlab.ui.control.Label
        SSPNEEditField                  matlab.ui.control.NumericEditField
        SAVSIEditFieldLabel             matlab.ui.control.Label
        SAVSIEditField                  matlab.ui.control.NumericEditField
        CHSTATEditFieldLabel            matlab.ui.control.Label
        CHSTATEditField                 matlab.ui.control.NumericEditField
        DHDADIEditFieldLabel            matlab.ui.control.Label
        DHDADIEditField                 matlab.ui.control.NumericEditField
        TWISTAEditFieldLabel            matlab.ui.control.Label
        TWISTAEditField                 matlab.ui.control.NumericEditField
        CHRDREditField                  matlab.ui.control.NumericEditField
        CHRDREditFieldLabel             matlab.ui.control.Label
        ChordTipLabel                   matlab.ui.control.Label
        ChordRootLabel                  matlab.ui.control.Label
        SemiSpanExposedLabel            matlab.ui.control.Label
        SemiSpanTheoreticalLabel_2      matlab.ui.control.Label
        SweepAngleLabel_2               matlab.ui.control.Label
        ChordRootLabel_8                matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_2  matlab.ui.control.Label
        DihedralAngleLabel_2            matlab.ui.control.Label
        SaveWingDataButton              matlab.ui.control.Button
        HorizontalTailTab               matlab.ui.container.Tab
        ChordTipLabel_2                 matlab.ui.control.Label
        ChordRootLabel_9                matlab.ui.control.Label
        SemiSpanExposedLabel_2          matlab.ui.control.Label
        SemiSpanTheoreticalLabel_3      matlab.ui.control.Label
        SweepAngleLabel_3               matlab.ui.control.Label
        ChordRootLabel_10               matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_3  matlab.ui.control.Label
        DihedralAngleLabel_3            matlab.ui.control.Label
        CHRDTPEditField_2Label          matlab.ui.control.Label
        CHRDTPEditField_2               matlab.ui.control.NumericEditField
        SSPNEditField_2Label            matlab.ui.control.Label
        SSPNEditField_2                 matlab.ui.control.NumericEditField
        SSPNEEditField_2Label           matlab.ui.control.Label
        SSPNEEditField_2                matlab.ui.control.NumericEditField
        SAVSIEditField_2Label           matlab.ui.control.Label
        SAVSIEditField_2                matlab.ui.control.NumericEditField
        CHSTATEditField_2Label          matlab.ui.control.Label
        CHSTATEditField_2               matlab.ui.control.NumericEditField
        DHDADIEditField_2Label          matlab.ui.control.Label
        DHDADIEditField_2               matlab.ui.control.NumericEditField
        TWISTAEditField_2Label          matlab.ui.control.Label
        TWISTAEditField_2               matlab.ui.control.NumericEditField
        CHRDREditField_2                matlab.ui.control.NumericEditField
        CHRDREditField_2Label           matlab.ui.control.Label
        SaveHorizontalTailDataButton    matlab.ui.control.Button
        VerticalTailTab                 matlab.ui.container.Tab
        ChordTipLabel_3                 matlab.ui.control.Label
        ChordRootLabel_11               matlab.ui.control.Label
        SemiSpanExposedLabel_3          matlab.ui.control.Label
        SemiSpanTheoreticalLabel_4      matlab.ui.control.Label
        SweepAngleLabel_4               matlab.ui.control.Label
        ChordRootLabel_12               matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_4  matlab.ui.control.Label
        DihedralAngleLabel_4            matlab.ui.control.Label
        CHRDTPEditField_3Label          matlab.ui.control.Label
        CHRDTPEditField_3               matlab.ui.control.NumericEditField
        SSPNEditField_3Label            matlab.ui.control.Label
        SSPNEditField_3                 matlab.ui.control.NumericEditField
        SSPNEEditField_3Label           matlab.ui.control.Label
        SSPNEEditField_3                matlab.ui.control.NumericEditField
        SAVSIEditField_3Label           matlab.ui.control.Label
        SAVSIEditField_3                matlab.ui.control.NumericEditField
        CHSTATEditField_3Label          matlab.ui.control.Label
        CHSTATEditField_3               matlab.ui.control.NumericEditField
        DHDADIEditField_3Label          matlab.ui.control.Label
        DHDADIEditField_3               matlab.ui.control.NumericEditField
        TWISTAEditField_3Label          matlab.ui.control.Label
        TWISTAEditField_3               matlab.ui.control.NumericEditField
        CHRDREditField_3                matlab.ui.control.NumericEditField
        CHRDREditField_3Label           matlab.ui.control.Label
        SaveVerticalTailDataButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveControlCardsDataButton
        function SaveControlCardsDataButtonPushed(app, event)
            ControlCardsData = {};
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Imperial"
                ControlCardsData{1} = 'FT';
            else
                ControlCardsData{1} = 'M';
            end
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Degree"
                ControlCardsData{2} = 'DEG';
            else
                ControlCardsData{2} = 'RAD';
            end
            if app.DynamicDerivativesadvicemakeitonCheckBox.Value == 1
                ControlCardsData{3} = 'DAMP';
            else
                ControlCardsData{3} = 'Null';
            end
            if app.PartmoduleCheckBox.Value == 1
                ControlCardsData{4} = 'PART';
            else
                ControlCardsData{4} = 'Null';
            end
            if app.BuildmoduleCheckBox.Value == 1
                ControlCardsData{5} = 'BUILD';
            else
                ControlCardsData{5} = 'Null';
            end
            writer(ControlCardsData, 'ControlCards');
        end

        % Button pushed function: SaveOptionsDataButton
        function SaveOptionsDataButtonPushed(app, event)
            OptionsData = {};
            OptionsData{1} = app.ReferenceAreaEditField.Value;
            OptionsData{2} = app.MeanAerodynamcChordEditField.Value;
            OptionsData{3} = app.WingSpanEditField.Value;
            OptionsData{4} = app.SurfaceRoughnessEditField.Value;
            writer(OptionsData, 'Options');
        end

        % Value changed function: SaveSynthesisDataButton
        function SaveSynthesisDataButtonValueChanged(app, event)
            %value = app.SaveSynthesisDataButton.Value;
            SynthesisData = {};
            SynthesisData{1} = app.XCGEditField.Value;
            SynthesisData{2} = app.ZCGEditField.Value;
            SynthesisData{3} = app.XWEditField.Value;
            SynthesisData{4} = app.ZWEditField.Value;
            SynthesisData{5} = app.ALIWEditField.Value;
            SynthesisData{6} = app.ALIHEditField.Value;
            SynthesisData{7} = app.XHEditField.Value;
            SynthesisData{8} = app.ZHEditField.Value;
            SynthesisData{9} = app.XHEditField.Value;
            SynthesisData{10} = app.ZVEditField.Value;
            SynthesisData{11} = app.SCALEEditField.Value;
            writer(SynthesisData, 'Synthesis');
        end

        % Button pushed function: SaveWingDataButton
        function SaveWingDataButtonPushed(app, event)
            WingData = {};
            WingData{1} = app.CHRDTPEditField.Value;
            WingData{2} = app.CHRDREditField.Value;
            WingData{3} = app.SSPNEEditField.Value;
            WingData{4} = app.SSPNEditField.Value;
            WingData{5} = app.SAVSIEditField.Value;
            WingData{6} = app.CHSTATEditField.Value;
            WingData{7} = app.TWISTAEditField.Value;
            WingData{8} = app.DHDADIEditField.Value;
            writer(WingData, 'Wing');
        end

        % Button pushed function: SaveHorizontalTailDataButton
        function SaveHorizontalTailDataButtonPushed(app, event)
            HTData = {};
            HTData{1} = app.CHRDTPEditField_2.Value;
            HTData{2} = app.CHRDREditField_2.Value;
            HTData{3} = app.SSPNEEditField_2.Value;
            HTData{4} = app.SSPNEditField_2.Value;
            HTData{5} = app.SAVSIEditField_2.Value;
            HTData{6} = app.CHSTATEditField_2.Value;
            HTData{7} = app.TWISTAEditField_2.Value;
            HTData{8} = app.DHDADIEditField_2.Value;
            writer(HTData, 'HT');
        end

        % Button pushed function: SaveVerticalTailDataButton
        function SaveVerticalTailDataButtonPushed(app, event)
            VTData = {};
            VTData{1} = app.CHRDTPEditField_3.Value;
            VTData{2} = app.CHRDREditField_3.Value;
            VTData{3} = app.SSPNEEditField_3.Value;
            VTData{4} = app.SSPNEditField_3.Value;
            VTData{5} = app.SAVSIEditField_3.Value;
            VTData{6} = app.CHSTATEditField_3.Value;
            VTData{7} = app.TWISTAEditField_3.Value;
            VTData{8} = app.DHDADIEditField_3.Value;
            writer(VTData, 'VT');
        end

        % Value changed function: FlightConditionsSaveDataButton
        function FlightConditionsSaveDataButtonValueChanged(app, event)
            TakeOffWeight = {app.TakeoffWeightEditField.Value};
            writer(TakeOffWeight, 'TOW');
            M_min = app.MinMachNumberEditField.Value;
            M_max = app.MaxMachNumberEditField.Value;
            M_num = app.NumberofMachnumbersMAX20EditField.Value;
            MachData = linspace(M_min, M_max, M_num);
            writer_matrix(MachData, 'Mach');
            A_min = app.MaxAltitudesEditField.Value;
            A_max = app.MinAltitudesEditField.Value;
            A_num = app.NumberofAltitudesMAX20EditField.Value;
            AltitudeData = linspace(A_min, A_max, A_num);
            writer_matrix(AltitudeData, 'Altitude');
            % AA Angle of Attack
            AA_min = app.MinAngleofAttacksEditField.Value;
            AA_max = app.MaxAngleofAttacksEditField.Value;
            AA_num = app.NumberofAngleofAttacksMAX20EditField.Value;
            AngleOfAttackData = linspace(AA_min, AA_max, AA_num);
            writer_matrix(AngleOfAttackData, 'AngleOfAttack');
            LoopText = app.ProgramLoopingControlButtonGroup.SelectedObject.Text ;
            if LoopText == "Vary altitude and Mach together"
                LoopData = 1;
            elseif LoopText == "Vary Mach at fixed altitude"
                LoopData = 2;
            else
                LoopData = 3;
            end
            writer_matrix(LoopData, 'Loop');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlCardsTab = uitab(app.TabGroup);
            app.ControlCardsTab.Title = 'Control Cards';
            app.DimensionsUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DimensionsUnitButtonGroup.Title = 'Dimensions Unit';
            app.ImperialButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.ImperialButton.Text = 'Imperial';
            app.ImperialButton.Value = true;
            app.MetricButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.MetricButton.Text = 'Metric';
            app.DerivativesUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DerivativesUnitButtonGroup.Title = 'Derivatives Unit';
            app.DegreeButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.DegreeButton.Text = 'Degree';
            app.DegreeButton.Value = true;
            app.RadianButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.RadianButton.Text = 'Radian';
            app.OtherOptionsPanel = uipanel(app.ControlCardsTab);
            app.OtherOptionsPanel.Title = 'Other Options';
            app.DynamicDerivativesadvicemakeitonCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.DynamicDerivativesadvicemakeitonCheckBox.Text = 'Dynamic Derivatives(advice: make it on)';
            app.PartmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.PartmoduleCheckBox.Text = 'Part module';
            app.BuildmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.BuildmoduleCheckBox.Text = 'Build module';
            app.SaveControlCardsDataButton = uibutton(app.ControlCardsTab, 'push');
            app.SaveControlCardsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveControlCardsDataButtonPushed, true);
            app.SaveControlCardsDataButton.Text = 'Save Control Cards Data';
            app.FlightConditionsTab = uitab(app.TabGroup);
            app.FlightConditionsTab.Title = 'Flight Conditions';
            app.TakeoffWeightEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.TakeoffWeightEditFieldLabel.Text = 'Take-off Weight';
            app.TakeoffWeightEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.TakeoffWeightEditField.Value = 59000;
            app.MinMachNumberEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinMachNumberEditFieldLabel.Text = 'Min Mach Number';
            app.MinMachNumberEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MinMachNumberEditField.Value = 0.1;
            app.MaxMachNumberEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxMachNumberEditFieldLabel.Text = 'Max Mach Number';
            app.MaxMachNumberEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxMachNumberEditField.Value = 0.8;
            app.NumberofMachnumbersMAX20Label = uilabel(app.FlightConditionsTab);
            app.NumberofMachnumbersMAX20Label.Text = 'Number of Mach numbers(MAX = 20)';
            app.NumberofMachnumbersMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofMachnumbersMAX20EditField.Value = 5;
            app.MinAltitudesEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinAltitudesEditFieldLabel.Text = 'Min Altitudes';
            app.MinAltitudesEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAltitudesEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxAltitudesEditFieldLabel.Text = 'Max Altitudes';
            app.MaxAltitudesEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAltitudesEditField.Value = 40000;
            app.NumberofAltitudesMAX20EditFieldLabel = uilabel(app.FlightConditionsTab);
            app.NumberofAltitudesMAX20EditFieldLabel.Text = 'Number of Altitudes(MAX = 20)';
            app.NumberofAltitudesMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofAltitudesMAX20EditField.Value = 20;
            app.MinAngleofAttacksEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinAngleofAttacksEditFieldLabel.Text = 'Min Angle of Attacks';
            app.MinAngleofAttacksEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MinAngleofAttacksEditField.Value = -16;
            app.MaxAngleofAttacksEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxAngleofAttacksEditFieldLabel.Text = 'Max Angle of Attacks';
            app.MaxAngleofAttacksEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAngleofAttacksEditField.Value = 16;
            app.NumberofAngleofAttacksMAX20EditFieldLabel = uilabel(app.FlightConditionsTab);
            app.NumberofAngleofAttacksMAX20EditFieldLabel.Text = 'Number of Angle of Attacks(MAX = 20)';
            app.NumberofAngleofAttacksMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofAngleofAttacksMAX20EditField.Value = 15;
            app.ProgramLoopingControlButtonGroup = uibuttongroup(app.FlightConditionsTab);
            app.ProgramLoopingControlButtonGroup.Title = 'Program Looping Control';
            app.VaryaltitudeandMachtogetherButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryaltitudeandMachtogetherButton.Text = 'Vary altitude and Mach together';
            app.VaryaltitudeandMachtogetherButton.Value = true;
            app.VaryMachatfixedaltitudeButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryMachatfixedaltitudeButton.Text = 'Vary Mach at fixed altitude';
            app.VaryaltitudeatfixedMachButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryaltitudeatfixedMachButton.Text = 'Vary altitude at fixed Mach';
            app.FlightConditionsSaveDataButton = uibutton(app.FlightConditionsTab, 'state');
            app.FlightConditionsSaveDataButton.ValueChangedFcn = createCallbackFcn(app, @FlightConditionsSaveDataButtonValueChanged, true);
            app.FlightConditionsSaveDataButton.Text = 'Flight Conditions Save Data';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ReferenceAreaEditFieldLabel = uilabel(app.OptionsTab);
            app.ReferenceAreaEditFieldLabel.Text = 'Reference Area';
            app.ReferenceAreaEditField = uieditfield(app.OptionsTab, 'numeric');
            app.ReferenceAreaEditField.Value = 702;
            app.MeanAerodynamcChordEditFieldLabel = uilabel(app.OptionsTab);
            app.MeanAerodynamcChordEditFieldLabel.Text = 'Mean Aerodynamc Chord';
            app.MeanAerodynamcChordEditField = uieditfield(app.OptionsTab, 'numeric');
            app.MeanAerodynamcChordEditField.Value = 8.167;
            app.WingSpanEditFieldLabel = uilabel(app.OptionsTab);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.OptionsTab, 'numeric');
            app.WingSpanEditField.Value = 81.683;
            app.SurfaceRoughnessEditFieldLabel = uilabel(app.OptionsTab);
            app.SurfaceRoughnessEditFieldLabel.Text = 'Surface Roughness';
            app.SurfaceRoughnessEditField = uieditfield(app.OptionsTab, 'numeric');
            app.SurfaceRoughnessEditField.Value = 0.00025;
            app.SaveOptionsDataButton = uibutton(app.OptionsTab, 'push');
            app.SaveOptionsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOptionsDataButtonPushed, true);
            app.SaveOptionsDataButton.Text = 'Save Options Data';
            app.SynthesisTab = uitab(app.TabGroup);
            app.SynthesisTab.Title = 'Synthesis';
            app.SaveSynthesisDataButton = uibutton(app.SynthesisTab, 'state');
            app.SaveSynthesisDataButton.ValueChangedFcn = createCallbackFcn(app, @SaveSynthesisDataButtonValueChanged, true);
            app.SaveSynthesisDataButton.Text = 'Save Synthesis Data';
            app.LongitudinalLocationofCGLabel = uilabel(app.SynthesisTab);
            app.LongitudinalLocationofCGLabel.Text = 'Longitudinal Location of CG';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel.Text = 'Vertical Location of CG(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_2 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_2.Text = 'Longitudinal Location of theoretical wing Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_3 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_3.Text = 'Vertical Location of theoretical wing Apex(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_4 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_4.Text = 'wing root chord incidence angle(measured from reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_5 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_5.Text = 'Longitudinal Location of theoretical horizontal tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_6 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_6.Text = 'Vertical Location of theoretical vertical tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_7 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_7.Text = 'Longitudinal Location of theoretical vertical tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_8 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_8.Text = 'Vertical Location of theoretical horizontal tail Apex(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_9 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_9.Text = ' horizontal tail root chord incidence angle(measured from reference plane)';
            app.XCGEditFieldLabel = uilabel(app.SynthesisTab);
            app.XCGEditFieldLabel.Text = 'XCG';
            app.XCGEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZWEditFieldLabel = uilabel(app.SynthesisTab);
            app.ZWEditFieldLabel.Text = 'ZW';
            app.ZWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XWLabel = uilabel(app.SynthesisTab);
            app.XWLabel.Text = 'XW';
            app.XWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ALIWEditFieldLabel = uilabel(app.SynthesisTab);
            app.ALIWEditFieldLabel.Text = 'ALIW';
            app.ALIWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ALIHEditFieldLabel = uilabel(app.SynthesisTab);
            app.ALIHEditFieldLabel.Text = 'ALIH';
            app.ALIHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZHLabel = uilabel(app.SynthesisTab);
            app.ZHLabel.Text = 'ZH';
            app.ZHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XHLabel = uilabel(app.SynthesisTab);
            app.XHLabel.Text = 'XH';
            app.XHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XVLabel = uilabel(app.SynthesisTab);
            app.XVLabel.Text = 'XV';
            app.XVEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZVLabel = uilabel(app.SynthesisTab);
            app.ZVLabel.Text = 'ZV';
            app.ZVEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.SCALELabel = uilabel(app.SynthesisTab);
            app.SCALELabel.Text = 'SCALE';
            app.SCALEEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.SCALEEditField.Value = 1;
            app.ZCGEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZCGEditFieldLabel = uilabel(app.SynthesisTab);
            app.ZCGEditFieldLabel.Text = 'ZCG';
            app.WingTab = uitab(app.TabGroup);
            app.WingTab.Title = 'Wing';
            app.CHRDTPEditFieldLabel = uilabel(app.WingTab);
            app.CHRDTPEditFieldLabel.Text = 'CHRDTP';
            app.CHRDTPEditField = uieditfield(app.WingTab, 'numeric');
            app.SSPNEditFieldLabel = uilabel(app.WingTab);
            app.SSPNEditFieldLabel.Text = 'SSPN';
            app.SSPNEditField = uieditfield(app.WingTab, 'numeric');
            app.SSPNEEditFieldLabel = uilabel(app.WingTab);
            app.SSPNEEditFieldLabel.Text = 'SSPNE';
            app.SSPNEEditField = uieditfield(app.WingTab, 'numeric');
            app.SAVSIEditFieldLabel = uilabel(app.WingTab);
            app.SAVSIEditFieldLabel.Text = 'SAVSI';
            app.SAVSIEditField = uieditfield(app.WingTab, 'numeric');
            app.CHSTATEditFieldLabel = uilabel(app.WingTab);
            app.CHSTATEditFieldLabel.Text = 'CHSTAT';
            app.CHSTATEditField = uieditfield(app.WingTab, 'numeric');
            app.DHDADIEditFieldLabel = uilabel(app.WingTab);
            app.DHDADIEditFieldLabel.Text = 'DHDADI';
            app.DHDADIEditField = uieditfield(app.WingTab, 'numeric');
            app.TWISTAEditFieldLabel = uilabel(app.WingTab);
            app.TWISTAEditFieldLabel.Text = 'TWISTA';
            app.TWISTAEditField = uieditfield(app.WingTab, 'numeric');
            app.CHRDREditField = uieditfield(app.WingTab, 'numeric');
            app.CHRDREditFieldLabel = uilabel(app.WingTab);
            app.CHRDREditFieldLabel.Text = 'CHRDR';
            app.ChordTipLabel = uilabel(app.WingTab);
            app.ChordTipLabel.Text = 'Chord Tip';
            app.ChordRootLabel = uilabel(app.WingTab);
            app.ChordRootLabel.Text = 'Chord Root';
            app.SemiSpanExposedLabel = uilabel(app.WingTab);
            app.SemiSpanExposedLabel.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_2 = uilabel(app.WingTab);
            app.SemiSpanTheoreticalLabel_2.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_2 = uilabel(app.WingTab);
            app.SweepAngleLabel_2.Text = 'Sweep Angle';
            app.ChordRootLabel_8 = uilabel(app.WingTab);
            app.ChordRootLabel_8.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_2 = uilabel(app.WingTab);
            app.TwistanglenegativeLErotateddownLabel_2.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_2 = uilabel(app.WingTab);
            app.DihedralAngleLabel_2.Text = 'Dihedral Angle';
            app.SaveWingDataButton = uibutton(app.WingTab, 'push');
            app.SaveWingDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWingDataButtonPushed, true);
            app.SaveWingDataButton.Text = 'Save Wing Data';
            app.HorizontalTailTab = uitab(app.TabGroup);
            app.HorizontalTailTab.Title = 'Horizontal Tail';
            app.ChordTipLabel_2 = uilabel(app.HorizontalTailTab);
            app.ChordTipLabel_2.Text = 'Chord Tip';
            app.ChordRootLabel_9 = uilabel(app.HorizontalTailTab);
            app.ChordRootLabel_9.Text = 'Chord Root';
            app.SemiSpanExposedLabel_2 = uilabel(app.HorizontalTailTab);
            app.SemiSpanExposedLabel_2.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_3 = uilabel(app.HorizontalTailTab);
            app.SemiSpanTheoreticalLabel_3.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_3 = uilabel(app.HorizontalTailTab);
            app.SweepAngleLabel_3.Text = 'Sweep Angle';
            app.ChordRootLabel_10 = uilabel(app.HorizontalTailTab);
            app.ChordRootLabel_10.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_3 = uilabel(app.HorizontalTailTab);
            app.TwistanglenegativeLErotateddownLabel_3.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_3 = uilabel(app.HorizontalTailTab);
            app.DihedralAngleLabel_3.Text = 'Dihedral Angle';
            app.CHRDTPEditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHRDTPEditField_2Label.Text = 'CHRDTP';
            app.CHRDTPEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SSPNEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SSPNEditField_2Label.Text = 'SSPN';
            app.SSPNEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SSPNEEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SSPNEEditField_2Label.Text = 'SSPNE';
            app.SSPNEEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SAVSIEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SAVSIEditField_2Label.Text = 'SAVSI';
            app.SAVSIEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHSTATEditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHSTATEditField_2Label.Text = 'CHSTAT';
            app.CHSTATEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.DHDADIEditField_2Label = uilabel(app.HorizontalTailTab);
            app.DHDADIEditField_2Label.Text = 'DHDADI';
            app.DHDADIEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.TWISTAEditField_2Label = uilabel(app.HorizontalTailTab);
            app.TWISTAEditField_2Label.Text = 'TWISTA';
            app.TWISTAEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHRDREditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHRDREditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHRDREditField_2Label.Text = 'CHRDR';
            app.SaveHorizontalTailDataButton = uibutton(app.HorizontalTailTab, 'push');
            app.SaveHorizontalTailDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHorizontalTailDataButtonPushed, true);
            app.SaveHorizontalTailDataButton.Text = 'Save Horizontal Tail Data';
            app.VerticalTailTab = uitab(app.TabGroup);
            app.VerticalTailTab.Title = 'Vertical Tail';
            app.ChordTipLabel_3 = uilabel(app.VerticalTailTab);
            app.ChordTipLabel_3.Text = 'Chord Tip';
            app.ChordRootLabel_11 = uilabel(app.VerticalTailTab);
            app.ChordRootLabel_11.Text = 'Chord Root';
            app.SemiSpanExposedLabel_3 = uilabel(app.VerticalTailTab);
            app.SemiSpanExposedLabel_3.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_4 = uilabel(app.VerticalTailTab);
            app.SemiSpanTheoreticalLabel_4.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_4 = uilabel(app.VerticalTailTab);
            app.SweepAngleLabel_4.Text = 'Sweep Angle';
            app.ChordRootLabel_12 = uilabel(app.VerticalTailTab);
            app.ChordRootLabel_12.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_4 = uilabel(app.VerticalTailTab);
            app.TwistanglenegativeLErotateddownLabel_4.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_4 = uilabel(app.VerticalTailTab);
            app.DihedralAngleLabel_4.Text = 'Dihedral Angle';
            app.CHRDTPEditField_3Label = uilabel(app.VerticalTailTab);
            app.CHRDTPEditField_3Label.Text = 'CHRDTP';
            app.CHRDTPEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SSPNEditField_3Label = uilabel(app.VerticalTailTab);
            app.SSPNEditField_3Label.Text = 'SSPN';
            app.SSPNEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SSPNEEditField_3Label = uilabel(app.VerticalTailTab);
            app.SSPNEEditField_3Label.Text = 'SSPNE';
            app.SSPNEEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SAVSIEditField_3Label = uilabel(app.VerticalTailTab);
            app.SAVSIEditField_3Label.Text = 'SAVSI';
            app.SAVSIEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHSTATEditField_3Label = uilabel(app.VerticalTailTab);
            app.CHSTATEditField_3Label.Text = 'CHSTAT';
            app.CHSTATEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.DHDADIEditField_3Label = uilabel(app.VerticalTailTab);
            app.DHDADIEditField_3Label.Text = 'DHDADI';
            app.DHDADIEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.TWISTAEditField_3Label = uilabel(app.VerticalTailTab);
            app.TWISTAEditField_3Label.Text = 'TWISTA';
            app.TWISTAEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHRDREditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHRDREditField_3Label = uilabel(app.VerticalTailTab);
            app.CHRDREditField_3Label.Text = 'CHRDR';
            app.SaveVerticalTailDataButton = uibutton(app.VerticalTailTab, 'push');
            app.SaveVerticalTailDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVerticalTailDataButtonPushed, true);
            app.SaveVerticalTailDataButton.Text = 'Save Vertical Tail Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATCOM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end