classdef DATCOM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControlCardsTab                 matlab.ui.container.Tab
        DimensionsUnitButtonGroup       matlab.ui.container.ButtonGroup
        ImperialButton                  matlab.ui.control.RadioButton
        MetricButton                    matlab.ui.control.RadioButton
        DerivativesUnitButtonGroup      matlab.ui.container.ButtonGroup
        DegreeButton                    matlab.ui.control.RadioButton
        RadianButton                    matlab.ui.control.RadioButton
        OtherOptionsPanel               matlab.ui.container.Panel
        DynamicDerivativesadvicemakeitonCheckBox  matlab.ui.control.CheckBox
        PartmoduleCheckBox              matlab.ui.control.CheckBox
        BuildmoduleCheckBox             matlab.ui.control.CheckBox
        SaveControlCardsDataButton      matlab.ui.control.Button
        DigitalDATCOMGUILabel           matlab.ui.control.Label
        SourcecodeavailableinLabel      matlab.ui.control.Label
        githubcomalibaniasad1999AirplaneDesignIILabel  matlab.ui.control.Label
        ContactUsLabel                  matlab.ui.control.Label
        alibaniasad1999yahoocomLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        AirplaneDesignIILabel           matlab.ui.control.Label
        DrBanazadehLabel                matlab.ui.control.Label
        FlightConditionsTab             matlab.ui.container.Tab
        TakeoffWeightEditFieldLabel     matlab.ui.control.Label
        TakeoffWeightEditField          matlab.ui.control.NumericEditField
        MinMachNumberEditFieldLabel     matlab.ui.control.Label
        MinMachNumberEditField          matlab.ui.control.NumericEditField
        MaxMachNumberEditFieldLabel     matlab.ui.control.Label
        MaxMachNumberEditField          matlab.ui.control.NumericEditField
        NumberofMachnumbersMAX20Label   matlab.ui.control.Label
        NumberofMachnumbersMAX20EditField  matlab.ui.control.NumericEditField
        MinAltitudesEditFieldLabel      matlab.ui.control.Label
        MinAltitudesEditField           matlab.ui.control.NumericEditField
        MaxAltitudesEditFieldLabel      matlab.ui.control.Label
        MaxAltitudesEditField           matlab.ui.control.NumericEditField
        NumberofAltitudesMAX20EditFieldLabel  matlab.ui.control.Label
        NumberofAltitudesMAX20EditField  matlab.ui.control.NumericEditField
        MinAngleofAttacksEditFieldLabel  matlab.ui.control.Label
        MinAngleofAttacksEditField      matlab.ui.control.NumericEditField
        MaxAngleofAttacksEditFieldLabel  matlab.ui.control.Label
        MaxAngleofAttacksEditField      matlab.ui.control.NumericEditField
        NumberofAngleofAttacksMAX20EditFieldLabel  matlab.ui.control.Label
        NumberofAngleofAttacksMAX20EditField  matlab.ui.control.NumericEditField
        ProgramLoopingControlButtonGroup  matlab.ui.container.ButtonGroup
        VaryaltitudeandMachtogetherButton  matlab.ui.control.RadioButton
        VaryMachatfixedaltitudeButton   matlab.ui.control.RadioButton
        VaryaltitudeatfixedMachButton   matlab.ui.control.RadioButton
        FlightConditionsSaveDataButton  matlab.ui.control.StateButton
        Image_3                         matlab.ui.control.Image
        OptionsTab                      matlab.ui.container.Tab
        ReferenceAreaEditFieldLabel     matlab.ui.control.Label
        ReferenceAreaEditField          matlab.ui.control.NumericEditField
        MeanAerodynamcChordEditFieldLabel  matlab.ui.control.Label
        MeanAerodynamcChordEditField    matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        SurfaceRoughnessEditFieldLabel  matlab.ui.control.Label
        SurfaceRoughnessEditField       matlab.ui.control.NumericEditField
        SaveOptionsDataButton           matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        SynthesisTab                    matlab.ui.container.Tab
        SaveSynthesisDataButton         matlab.ui.control.StateButton
        LongitudinalLocationofCGLabel   matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_2  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_3  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_4  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_5  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_6  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_7  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_8  matlab.ui.control.Label
        VerticalLocationofCGrelativetoreferenceplaneLabel_9  matlab.ui.control.Label
        XCGEditFieldLabel               matlab.ui.control.Label
        XCGEditField                    matlab.ui.control.NumericEditField
        ZWEditFieldLabel                matlab.ui.control.Label
        ZWEditField                     matlab.ui.control.NumericEditField
        XWLabel                         matlab.ui.control.Label
        XWEditField                     matlab.ui.control.NumericEditField
        ALIWEditFieldLabel              matlab.ui.control.Label
        ALIWEditField                   matlab.ui.control.NumericEditField
        ALIHEditFieldLabel              matlab.ui.control.Label
        ALIHEditField                   matlab.ui.control.NumericEditField
        ZHLabel                         matlab.ui.control.Label
        ZHEditField                     matlab.ui.control.NumericEditField
        XHLabel                         matlab.ui.control.Label
        XHEditField                     matlab.ui.control.NumericEditField
        XVLabel                         matlab.ui.control.Label
        XVEditField                     matlab.ui.control.NumericEditField
        ZVLabel                         matlab.ui.control.Label
        ZVEditField                     matlab.ui.control.NumericEditField
        SCALELabel                      matlab.ui.control.Label
        SCALEEditField                  matlab.ui.control.NumericEditField
        ZCGEditField                    matlab.ui.control.NumericEditField
        ZCGEditFieldLabel               matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        WingTab                         matlab.ui.container.Tab
        CHRDTPEditFieldLabel            matlab.ui.control.Label
        CHRDTPEditField                 matlab.ui.control.NumericEditField
        SSPNEditFieldLabel              matlab.ui.control.Label
        SSPNEditField                   matlab.ui.control.NumericEditField
        SSPNEEditFieldLabel             matlab.ui.control.Label
        SSPNEEditField                  matlab.ui.control.NumericEditField
        SAVSIEditFieldLabel             matlab.ui.control.Label
        SAVSIEditField                  matlab.ui.control.NumericEditField
        CHSTATEditFieldLabel            matlab.ui.control.Label
        CHSTATEditField                 matlab.ui.control.NumericEditField
        DHDADIEditFieldLabel            matlab.ui.control.Label
        DHDADIEditField                 matlab.ui.control.NumericEditField
        TWISTAEditFieldLabel            matlab.ui.control.Label
        TWISTAEditField                 matlab.ui.control.NumericEditField
        CHRDREditField                  matlab.ui.control.NumericEditField
        CHRDREditFieldLabel             matlab.ui.control.Label
        TipChordLabel                   matlab.ui.control.Label
        RootChordLabel                  matlab.ui.control.Label
        SemiSpanExposedLabel            matlab.ui.control.Label
        SemiSpanTheoreticalLabel_2      matlab.ui.control.Label
        SweepAngleLabel_2               matlab.ui.control.Label
        ChordRootLabel_8                matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_2  matlab.ui.control.Label
        DihedralAngleLabel_2            matlab.ui.control.Label
        SaveWingDataButton              matlab.ui.control.Button
        WingTypeButtonGroup             matlab.ui.container.ButtonGroup
        straighttaperedplatformButton   matlab.ui.control.RadioButton
        doubledeltaplatformAR3Button    matlab.ui.control.RadioButton
        CrankedplatformAR3Button        matlab.ui.control.RadioButton
        copyyourairfoilDatafromairfoiltoolscomsitetoAirfoiltxtfileLabel  matlab.ui.control.Label
        Image_6                         matlab.ui.control.Image
        HorizontalTailTab               matlab.ui.container.Tab
        TipChordLabel_2                 matlab.ui.control.Label
        RootChordLabel_2                matlab.ui.control.Label
        SemiSpanExposedLabel_2          matlab.ui.control.Label
        SemiSpanTheoreticalLabel_3      matlab.ui.control.Label
        SweepAngleLabel_3               matlab.ui.control.Label
        ChordRootLabel_10               matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_3  matlab.ui.control.Label
        DihedralAngleLabel_3            matlab.ui.control.Label
        CHRDTPEditField_2Label          matlab.ui.control.Label
        CHRDTPEditField_2               matlab.ui.control.NumericEditField
        SSPNEditField_2Label            matlab.ui.control.Label
        SSPNEditField_2                 matlab.ui.control.NumericEditField
        SSPNEEditField_2Label           matlab.ui.control.Label
        SSPNEEditField_2                matlab.ui.control.NumericEditField
        SAVSIEditField_2Label           matlab.ui.control.Label
        SAVSIEditField_2                matlab.ui.control.NumericEditField
        CHSTATEditField_2Label          matlab.ui.control.Label
        CHSTATEditField_2               matlab.ui.control.NumericEditField
        DHDADIEditField_2Label          matlab.ui.control.Label
        DHDADIEditField_2               matlab.ui.control.NumericEditField
        TWISTAEditField_2Label          matlab.ui.control.Label
        TWISTAEditField_2               matlab.ui.control.NumericEditField
        CHRDREditField_2                matlab.ui.control.NumericEditField
        CHRDREditField_2Label           matlab.ui.control.Label
        SaveHorizontalTailDataButton    matlab.ui.control.Button
        HorizontalTailTypeButtonGroup   matlab.ui.container.ButtonGroup
        straighttaperedplatformButton_2  matlab.ui.control.RadioButton
        doubledeltaplatformAR3Button_2  matlab.ui.control.RadioButton
        CrankedplatformAR3Button_2      matlab.ui.control.RadioButton
        WriteairfoilnamelikeNACAH40012Label  matlab.ui.control.Label
        AirfoilEditField_4Label         matlab.ui.control.Label
        AirfoilEditField_4              matlab.ui.control.EditField
        Image_7                         matlab.ui.control.Image
        VerticalTailTab                 matlab.ui.container.Tab
        TipChordLabel_3                 matlab.ui.control.Label
        RootChordLabel_3                matlab.ui.control.Label
        SemiSpanExposedLabel_3          matlab.ui.control.Label
        SemiSpanTheoreticalLabel_4      matlab.ui.control.Label
        SweepAngleLabel_4               matlab.ui.control.Label
        ChordRootLabel_12               matlab.ui.control.Label
        TwistanglenegativeLErotateddownLabel_4  matlab.ui.control.Label
        DihedralAngleLabel_4            matlab.ui.control.Label
        CHRDTPEditField_3Label          matlab.ui.control.Label
        CHRDTPEditField_3               matlab.ui.control.NumericEditField
        SSPNEditField_3Label            matlab.ui.control.Label
        SSPNEditField_3                 matlab.ui.control.NumericEditField
        SSPNEEditField_3Label           matlab.ui.control.Label
        SSPNEEditField_3                matlab.ui.control.NumericEditField
        SAVSIEditField_3Label           matlab.ui.control.Label
        SAVSIEditField_3                matlab.ui.control.NumericEditField
        CHSTATEditField_3Label          matlab.ui.control.Label
        CHSTATEditField_3               matlab.ui.control.NumericEditField
        DHDADIEditField_3Label          matlab.ui.control.Label
        DHDADIEditField_3               matlab.ui.control.NumericEditField
        TWISTAEditField_3Label          matlab.ui.control.Label
        TWISTAEditField_3               matlab.ui.control.NumericEditField
        CHRDREditField_3                matlab.ui.control.NumericEditField
        CHRDREditField_3Label           matlab.ui.control.Label
        SaveVerticalTailDataButton      matlab.ui.control.Button
        VerticalTailTypeButtonGroup     matlab.ui.container.ButtonGroup
        straighttaperedplatformButton_3  matlab.ui.control.RadioButton
        doubledeltaplatformAR3Button_3  matlab.ui.control.RadioButton
        CrankedplatformAR3Button_3      matlab.ui.control.RadioButton
        WriteairfoilnamelikeNACAV40012Label_2  matlab.ui.control.Label
        AirfoilEditField_3Label         matlab.ui.control.Label
        AirfoilEditField_3              matlab.ui.control.EditField
        Image_8                         matlab.ui.control.Image
        RunDATCOMTab                    matlab.ui.container.Tab
        MakeDtacomFileButton            matlab.ui.control.Button
        RunDATCOMButton                 matlab.ui.control.Button
        Image_9                         matlab.ui.control.Image
        Image_10                        matlab.ui.control.Image
        FirstmakeDTACOMfilethenrunthefileLabel  matlab.ui.control.Label
        NoteforchangebodyshapeeditBodypyLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveControlCardsDataButton
        function SaveControlCardsDataButtonPushed(app, event)
            ControlCardsData = {};
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Imperial"
                ControlCardsData{1} = 'FT';
            else
                ControlCardsData{1} = 'M';
            end
            if app.DimensionsUnitButtonGroup.SelectedObject.Text == "Degree"
                ControlCardsData{2} = 'DEG';
            else
                ControlCardsData{2} = 'RAD';
            end
            if app.DynamicDerivativesadvicemakeitonCheckBox.Value == 1
                ControlCardsData{3} = 'DAMP';
            else
                ControlCardsData{3} = 'Null';
            end
            if app.PartmoduleCheckBox.Value == 1
                ControlCardsData{4} = 'PART';
            else
                ControlCardsData{4} = 'Null';
            end
            if app.BuildmoduleCheckBox.Value == 1
                ControlCardsData{5} = 'BUILD';
            else
                ControlCardsData{5} = 'Null';
            end
            writer(ControlCardsData, 'ControlCards');
        end

        % Button pushed function: SaveOptionsDataButton
        function SaveOptionsDataButtonPushed(app, event)
            OptionsData = {};
            OptionsData{1} = app.ReferenceAreaEditField.Value;
            OptionsData{2} = app.MeanAerodynamcChordEditField.Value;
            OptionsData{3} = app.WingSpanEditField.Value;
            OptionsData{4} = app.SurfaceRoughnessEditField.Value;
            writer(OptionsData, 'Options');
        end

        % Value changed function: SaveSynthesisDataButton
        function SaveSynthesisDataButtonValueChanged(app, event)
            %value = app.SaveSynthesisDataButton.Value;
            SynthesisData = {};
            SynthesisData{1} = app.XCGEditField.Value;
            SynthesisData{2} = app.ZCGEditField.Value;
            SynthesisData{3} = app.XWEditField.Value;
            SynthesisData{4} = app.ZWEditField.Value;
            SynthesisData{5} = app.ALIWEditField.Value;
            SynthesisData{6} = app.ALIHEditField.Value;
            SynthesisData{7} = app.XHEditField.Value;
            SynthesisData{8} = app.ZHEditField.Value;
            SynthesisData{9} = app.XVEditField.Value;
            SynthesisData{10} = app.ZVEditField.Value;
            SynthesisData{11} = app.SCALEEditField.Value;
            writer(SynthesisData, 'Synthesis');
        end

        % Button pushed function: SaveWingDataButton
        function SaveWingDataButtonPushed(app, event)
            WingData = {};
            WingData{1} = app.CHRDTPEditField.Value;
            WingData{2} = app.CHRDREditField.Value;
            WingData{3} = app.SSPNEEditField.Value;
            WingData{4} = app.SSPNEditField.Value;
            WingData{5} = app.SAVSIEditField.Value;
            WingData{6} = app.CHSTATEditField.Value;
            WingData{7} = app.TWISTAEditField.Value;
            WingData{8} = app.DHDADIEditField.Value;
            if app.WingTypeButtonGroup.SelectedObject.Text...
                == "straight tapered platform"
                WingData{9} = 1;
            elseif app.WingTypeButtonGroup.SelectedObject.Text...
                    == "double delta platform AR<3"
                WingData{9} = 2;
            else
                WingData{9} = 3;
            end
            writer(WingData, 'Wing');
        end

        % Button pushed function: SaveHorizontalTailDataButton
        function SaveHorizontalTailDataButtonPushed(app, event)
            HTData = {};
            HTData{1} = app.CHRDTPEditField_2.Value;
            HTData{2} = app.CHRDREditField_2.Value;
            HTData{3} = app.SSPNEEditField_2.Value;
            HTData{4} = app.SSPNEditField_2.Value;
            HTData{5} = app.SAVSIEditField_2.Value;
            HTData{6} = app.CHSTATEditField_2.Value;
            HTData{7} = app.TWISTAEditField_2.Value;
            HTData{8} = app.DHDADIEditField_2.Value;
            if app.WingTypeButtonGroup.SelectedObject.Text...
                == "straight tapered platform"
                HTData{9} = 1;
            elseif app.WingTypeButtonGroup.SelectedObject.Text...
                    == "double delta platform AR<3"
                HTData{9} = 2;
            else
                HTData{9} = 3;
            end
            HTData{10} = app.AirfoilEditField_4.Value;
            writer(HTData, 'HT');
        end

        % Button pushed function: SaveVerticalTailDataButton
        function SaveVerticalTailDataButtonPushed(app, event)
            VTData = {};
            VTData{1} = app.CHRDTPEditField_3.Value;
            VTData{2} = app.CHRDREditField_3.Value;
            VTData{3} = app.SSPNEEditField_3.Value;
            VTData{4} = app.SSPNEditField_3.Value;
            VTData{5} = app.SAVSIEditField_3.Value;
            VTData{6} = app.CHSTATEditField_3.Value;
            VTData{7} = app.TWISTAEditField_3.Value;
            VTData{8} = app.DHDADIEditField_3.Value;
            if app.WingTypeButtonGroup.SelectedObject.Text...
                == "straight tapered platform"
                VTData{9} = 1;
            elseif app.WingTypeButtonGroup.SelectedObject.Text...
                    == "double delta platform AR<3"
                VTData{9} = 2;
            else
                VTData{9} = 3;
            end
            VTData{10} = app.AirfoilEditField_3.Value;
            writer(VTData, 'VT');
        end

        % Value changed function: FlightConditionsSaveDataButton
        function FlightConditionsSaveDataButtonValueChanged(app, event)
            TakeOffWeight = {app.TakeoffWeightEditField.Value};
            writer(TakeOffWeight, 'TOW');
            M_min = app.MinMachNumberEditField.Value;
            M_max = app.MaxMachNumberEditField.Value;
            M_num = app.NumberofMachnumbersMAX20EditField.Value;
            MachData = linspace(M_min, M_max, M_num);
            writer_matrix(MachData, 'Mach');
            A_min = app.MaxAltitudesEditField.Value;
            A_max = app.MinAltitudesEditField.Value;
            A_num = app.NumberofAltitudesMAX20EditField.Value;
            AltitudeData = linspace(A_min, A_max, A_num);
            writer_matrix(AltitudeData, 'Altitude');
            % AA Angle of Attack
            AA_min = app.MinAngleofAttacksEditField.Value;
            AA_max = app.MaxAngleofAttacksEditField.Value;
            AA_num = app.NumberofAngleofAttacksMAX20EditField.Value;
            AngleOfAttackData = linspace(AA_min, AA_max, AA_num);
            writer_matrix(AngleOfAttackData, 'AngleOfAttack');
            LoopText = app.ProgramLoopingControlButtonGroup.SelectedObject.Text ;
            if LoopText == "Vary altitude and Mach together"
                LoopData = 1;
            elseif LoopText == "Vary Mach at fixed altitude"
                LoopData = 2;
            else
                LoopData = 3;
            end
            writer_matrix(LoopData, 'Loop');
        end

        % Button pushed function: MakeDtacomFileButton
        function MakeDtacomFileButtonPushed(app, event)
            system('main.py');
        end

        % Button pushed function: RunDATCOMButton
        function RunDATCOMButtonPushed(app, event)
            system('Phoenix.dcm &')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlCardsTab = uitab(app.TabGroup);
            app.ControlCardsTab.Title = 'Control Cards';
            app.DimensionsUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DimensionsUnitButtonGroup.Title = 'Dimensions Unit';
            app.ImperialButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.ImperialButton.Text = 'Imperial';
            app.ImperialButton.Value = true;
            app.MetricButton = uiradiobutton(app.DimensionsUnitButtonGroup);
            app.MetricButton.Text = 'Metric';
            app.DerivativesUnitButtonGroup = uibuttongroup(app.ControlCardsTab);
            app.DerivativesUnitButtonGroup.Title = 'Derivatives Unit';
            app.DegreeButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.DegreeButton.Text = 'Degree';
            app.DegreeButton.Value = true;
            app.RadianButton = uiradiobutton(app.DerivativesUnitButtonGroup);
            app.RadianButton.Text = 'Radian';
            app.OtherOptionsPanel = uipanel(app.ControlCardsTab);
            app.OtherOptionsPanel.Title = 'Other Options';
            app.DynamicDerivativesadvicemakeitonCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.DynamicDerivativesadvicemakeitonCheckBox.Text = 'Dynamic Derivatives(advice: make it on)';
            app.PartmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.PartmoduleCheckBox.Text = 'Part module';
            app.BuildmoduleCheckBox = uicheckbox(app.OtherOptionsPanel);
            app.BuildmoduleCheckBox.Text = 'Build module';
            app.SaveControlCardsDataButton = uibutton(app.ControlCardsTab, 'push');
            app.SaveControlCardsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveControlCardsDataButtonPushed, true);
            app.SaveControlCardsDataButton.Text = 'Save Control Cards Data';
            app.DigitalDATCOMGUILabel = uilabel(app.ControlCardsTab);
            app.DigitalDATCOMGUILabel.Text = 'Digital DATCOM GUI';
            app.SourcecodeavailableinLabel = uilabel(app.ControlCardsTab);
            app.SourcecodeavailableinLabel.Text = 'Source code available in:';
            app.githubcomalibaniasad1999AirplaneDesignIILabel = uilabel(app.ControlCardsTab);
            app.githubcomalibaniasad1999AirplaneDesignIILabel.Text = 'github.com/alibaniasad1999/Airplane-Design-II';
            app.ContactUsLabel = uilabel(app.ControlCardsTab);
            app.ContactUsLabel.Text = 'Contact Us:';
            app.alibaniasad1999yahoocomLabel = uilabel(app.ControlCardsTab);
            app.alibaniasad1999yahoocomLabel.Text = 'alibaniasad1999@yahoo.com';
            app.Image = uiimage(app.ControlCardsTab);
            app.Image.ImageSource = 'phlogo.png';
            app.Image_2 = uiimage(app.ControlCardsTab);
            app.Image_2.ImageSource = 'logo-en.png';
            app.AirplaneDesignIILabel = uilabel(app.ControlCardsTab);
            app.AirplaneDesignIILabel.Text = 'Airplane Design II';
            app.DrBanazadehLabel = uilabel(app.ControlCardsTab);
            app.DrBanazadehLabel.Text = 'Dr. Banazadeh';
            app.FlightConditionsTab = uitab(app.TabGroup);
            app.FlightConditionsTab.Title = 'Flight Conditions';
            app.TakeoffWeightEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.TakeoffWeightEditFieldLabel.Text = 'Take-off Weight';
            app.TakeoffWeightEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.TakeoffWeightEditField.Value = 59000;
            app.MinMachNumberEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinMachNumberEditFieldLabel.Text = 'Min Mach Number';
            app.MinMachNumberEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MinMachNumberEditField.Value = 0.1;
            app.MaxMachNumberEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxMachNumberEditFieldLabel.Text = 'Max Mach Number';
            app.MaxMachNumberEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxMachNumberEditField.Value = 0.8;
            app.NumberofMachnumbersMAX20Label = uilabel(app.FlightConditionsTab);
            app.NumberofMachnumbersMAX20Label.Text = 'Number of Mach numbers(MAX = 20)';
            app.NumberofMachnumbersMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofMachnumbersMAX20EditField.Value = 5;
            app.MinAltitudesEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinAltitudesEditFieldLabel.Text = 'Min Altitudes';
            app.MinAltitudesEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAltitudesEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxAltitudesEditFieldLabel.Text = 'Max Altitudes';
            app.MaxAltitudesEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAltitudesEditField.Value = 40000;
            app.NumberofAltitudesMAX20EditFieldLabel = uilabel(app.FlightConditionsTab);
            app.NumberofAltitudesMAX20EditFieldLabel.Text = 'Number of Altitudes(MAX = 20)';
            app.NumberofAltitudesMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofAltitudesMAX20EditField.Value = 20;
            app.MinAngleofAttacksEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MinAngleofAttacksEditFieldLabel.Text = 'Min Angle of Attacks';
            app.MinAngleofAttacksEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MinAngleofAttacksEditField.Value = -16;
            app.MaxAngleofAttacksEditFieldLabel = uilabel(app.FlightConditionsTab);
            app.MaxAngleofAttacksEditFieldLabel.Text = 'Max Angle of Attacks';
            app.MaxAngleofAttacksEditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.MaxAngleofAttacksEditField.Value = 16;
            app.NumberofAngleofAttacksMAX20EditFieldLabel = uilabel(app.FlightConditionsTab);
            app.NumberofAngleofAttacksMAX20EditFieldLabel.Text = 'Number of Angle of Attacks(MAX = 20)';
            app.NumberofAngleofAttacksMAX20EditField = uieditfield(app.FlightConditionsTab, 'numeric');
            app.NumberofAngleofAttacksMAX20EditField.Value = 15;
            app.ProgramLoopingControlButtonGroup = uibuttongroup(app.FlightConditionsTab);
            app.ProgramLoopingControlButtonGroup.Title = 'Program Looping Control';
            app.VaryaltitudeandMachtogetherButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryaltitudeandMachtogetherButton.Text = 'Vary altitude and Mach together';
            app.VaryaltitudeandMachtogetherButton.Value = true;
            app.VaryMachatfixedaltitudeButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryMachatfixedaltitudeButton.Text = 'Vary Mach at fixed altitude';
            app.VaryaltitudeatfixedMachButton = uiradiobutton(app.ProgramLoopingControlButtonGroup);
            app.VaryaltitudeatfixedMachButton.Text = 'Vary altitude at fixed Mach';
            app.FlightConditionsSaveDataButton = uibutton(app.FlightConditionsTab, 'state');
            app.FlightConditionsSaveDataButton.ValueChangedFcn = createCallbackFcn(app, @FlightConditionsSaveDataButtonValueChanged, true);
            app.FlightConditionsSaveDataButton.Text = 'Flight Conditions Save Data';
            app.Image_3 = uiimage(app.FlightConditionsTab);
            app.Image_3.ImageSource = 'phlogo.png';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ReferenceAreaEditFieldLabel = uilabel(app.OptionsTab);
            app.ReferenceAreaEditFieldLabel.Text = 'Reference Area';
            app.ReferenceAreaEditField = uieditfield(app.OptionsTab, 'numeric');
            app.ReferenceAreaEditField.Value = 702;
            app.MeanAerodynamcChordEditFieldLabel = uilabel(app.OptionsTab);
            app.MeanAerodynamcChordEditFieldLabel.Text = 'Mean Aerodynamc Chord';
            app.MeanAerodynamcChordEditField = uieditfield(app.OptionsTab, 'numeric');
            app.MeanAerodynamcChordEditField.Value = 8.167;
            app.WingSpanEditFieldLabel = uilabel(app.OptionsTab);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.OptionsTab, 'numeric');
            app.WingSpanEditField.Value = 81.683;
            app.SurfaceRoughnessEditFieldLabel = uilabel(app.OptionsTab);
            app.SurfaceRoughnessEditFieldLabel.Text = 'Surface Roughness';
            app.SurfaceRoughnessEditField = uieditfield(app.OptionsTab, 'numeric');
            app.SurfaceRoughnessEditField.Value = 0.00025;
            app.SaveOptionsDataButton = uibutton(app.OptionsTab, 'push');
            app.SaveOptionsDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOptionsDataButtonPushed, true);
            app.SaveOptionsDataButton.Text = 'Save Options Data';
            app.Image_4 = uiimage(app.OptionsTab);
            app.Image_4.ImageSource = 'phlogo.png';
            app.SynthesisTab = uitab(app.TabGroup);
            app.SynthesisTab.Title = 'Synthesis';
            app.SaveSynthesisDataButton = uibutton(app.SynthesisTab, 'state');
            app.SaveSynthesisDataButton.ValueChangedFcn = createCallbackFcn(app, @SaveSynthesisDataButtonValueChanged, true);
            app.SaveSynthesisDataButton.Text = 'Save Synthesis Data';
            app.LongitudinalLocationofCGLabel = uilabel(app.SynthesisTab);
            app.LongitudinalLocationofCGLabel.Text = 'Longitudinal Location of CG';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel.Text = 'Vertical Location of CG(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_2 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_2.Text = 'Longitudinal Location of theoretical wing Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_3 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_3.Text = 'Vertical Location of theoretical wing Apex(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_4 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_4.Text = 'wing root chord incidence angle(measured from reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_5 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_5.Text = 'Longitudinal Location of theoretical horizontal tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_6 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_6.Text = 'Vertical Location of theoretical vertical tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_7 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_7.Text = 'Longitudinal Location of theoretical vertical tail Apex';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_8 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_8.Text = 'Vertical Location of theoretical horizontal tail Apex(relative to reference plane)';
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_9 = uilabel(app.SynthesisTab);
            app.VerticalLocationofCGrelativetoreferenceplaneLabel_9.Text = ' horizontal tail root chord incidence angle(measured from reference plane)';
            app.XCGEditFieldLabel = uilabel(app.SynthesisTab);
            app.XCGEditFieldLabel.Text = 'XCG';
            app.XCGEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XCGEditField.Value = 39.208;
            app.ZWEditFieldLabel = uilabel(app.SynthesisTab);
            app.ZWEditFieldLabel.Text = 'ZW';
            app.ZWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZWEditField.Value = -4.853;
            app.XWLabel = uilabel(app.SynthesisTab);
            app.XWLabel.Text = 'XW';
            app.XWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XWEditField.Value = 28.75;
            app.ALIWEditFieldLabel = uilabel(app.SynthesisTab);
            app.ALIWEditFieldLabel.Text = 'ALIW';
            app.ALIWEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ALIWEditField.Value = 1.3;
            app.ALIHEditFieldLabel = uilabel(app.SynthesisTab);
            app.ALIHEditFieldLabel.Text = 'ALIH';
            app.ALIHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ALIHEditField.Value = -2;
            app.ZHLabel = uilabel(app.SynthesisTab);
            app.ZHLabel.Text = 'ZH';
            app.ZHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZHEditField.Value = 11.2;
            app.XHLabel = uilabel(app.SynthesisTab);
            app.XHLabel.Text = 'XH';
            app.XHEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XHEditField.Value = 69.167;
            app.XVLabel = uilabel(app.SynthesisTab);
            app.XVLabel.Text = 'XV';
            app.XVEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.XVEditField.Value = 57.583;
            app.ZVLabel = uilabel(app.SynthesisTab);
            app.ZVLabel.Text = 'ZV';
            app.ZVEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZVEditField.Value = 1;
            app.SCALELabel = uilabel(app.SynthesisTab);
            app.SCALELabel.Text = 'SCALE';
            app.SCALEEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.SCALEEditField.Value = 1;
            app.ZCGEditField = uieditfield(app.SynthesisTab, 'numeric');
            app.ZCGEditFieldLabel = uilabel(app.SynthesisTab);
            app.ZCGEditFieldLabel.Text = 'ZCG';
            app.Image_5 = uiimage(app.SynthesisTab);
            app.Image_5.ImageSource = 'phlogo.png';
            app.WingTab = uitab(app.TabGroup);
            app.WingTab.Title = 'Wing';
            app.CHRDTPEditFieldLabel = uilabel(app.WingTab);
            app.CHRDTPEditFieldLabel.Text = 'CHRDTP';
            app.CHRDTPEditField = uieditfield(app.WingTab, 'numeric');
            app.CHRDTPEditField.Value = 4.34;
            app.SSPNEditFieldLabel = uilabel(app.WingTab);
            app.SSPNEditFieldLabel.Text = 'SSPN';
            app.SSPNEditField = uieditfield(app.WingTab, 'numeric');
            app.SSPNEditField.Value = 40.845;
            app.SSPNEEditFieldLabel = uilabel(app.WingTab);
            app.SSPNEEditFieldLabel.Text = 'SSPNE';
            app.SSPNEEditField = uieditfield(app.WingTab, 'numeric');
            app.SSPNEEditField.Value = 39;
            app.SAVSIEditFieldLabel = uilabel(app.WingTab);
            app.SAVSIEditFieldLabel.Text = 'SAVSI';
            app.SAVSIEditField = uieditfield(app.WingTab, 'numeric');
            app.SAVSIEditField.Value = 30;
            app.CHSTATEditFieldLabel = uilabel(app.WingTab);
            app.CHSTATEditFieldLabel.Text = 'CHSTAT';
            app.CHSTATEditField = uieditfield(app.WingTab, 'numeric');
            app.CHSTATEditField.Value = 0.5;
            app.DHDADIEditFieldLabel = uilabel(app.WingTab);
            app.DHDADIEditFieldLabel.Text = 'DHDADI';
            app.DHDADIEditField = uieditfield(app.WingTab, 'numeric');
            app.DHDADIEditField.Value = 4;
            app.TWISTAEditFieldLabel = uilabel(app.WingTab);
            app.TWISTAEditFieldLabel.Text = 'TWISTA';
            app.TWISTAEditField = uieditfield(app.WingTab, 'numeric');
            app.CHRDREditField = uieditfield(app.WingTab, 'numeric');
            app.CHRDREditField.Value = 15.5;
            app.CHRDREditFieldLabel = uilabel(app.WingTab);
            app.CHRDREditFieldLabel.Text = 'CHRDR';
            app.TipChordLabel = uilabel(app.WingTab);
            app.TipChordLabel.Text = 'Tip Chord';
            app.RootChordLabel = uilabel(app.WingTab);
            app.RootChordLabel.Text = 'Root Chord';
            app.SemiSpanExposedLabel = uilabel(app.WingTab);
            app.SemiSpanExposedLabel.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_2 = uilabel(app.WingTab);
            app.SemiSpanTheoreticalLabel_2.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_2 = uilabel(app.WingTab);
            app.SweepAngleLabel_2.Text = 'Sweep Angle';
            app.ChordRootLabel_8 = uilabel(app.WingTab);
            app.ChordRootLabel_8.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_2 = uilabel(app.WingTab);
            app.TwistanglenegativeLErotateddownLabel_2.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_2 = uilabel(app.WingTab);
            app.DihedralAngleLabel_2.Text = 'Dihedral Angle';
            app.SaveWingDataButton = uibutton(app.WingTab, 'push');
            app.SaveWingDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWingDataButtonPushed, true);
            app.SaveWingDataButton.Text = 'Save Wing Data';
            app.WingTypeButtonGroup = uibuttongroup(app.WingTab);
            app.WingTypeButtonGroup.Title = 'Wing Type';
            app.straighttaperedplatformButton = uiradiobutton(app.WingTypeButtonGroup);
            app.straighttaperedplatformButton.Text = 'straight tapered platform';
            app.straighttaperedplatformButton.Value = true;
            app.doubledeltaplatformAR3Button = uiradiobutton(app.WingTypeButtonGroup);
            app.doubledeltaplatformAR3Button.Text = 'double delta platform AR<3';
            app.CrankedplatformAR3Button = uiradiobutton(app.WingTypeButtonGroup);
            app.CrankedplatformAR3Button.Text = 'Cranked platform AR>3';
            app.copyyourairfoilDatafromairfoiltoolscomsitetoAirfoiltxtfileLabel = uilabel(app.WingTab);
            app.copyyourairfoilDatafromairfoiltoolscomsitetoAirfoiltxtfileLabel.Text = 'copy your airfoil Data from airfoiltools.com site to Airfoil.txt file';
            app.Image_6 = uiimage(app.WingTab);
            app.Image_6.ImageSource = 'phlogo.png';
            app.HorizontalTailTab = uitab(app.TabGroup);
            app.HorizontalTailTab.Title = 'Horizontal Tail';
            app.TipChordLabel_2 = uilabel(app.HorizontalTailTab);
            app.TipChordLabel_2.Text = 'Tip Chord';
            app.RootChordLabel_2 = uilabel(app.HorizontalTailTab);
            app.RootChordLabel_2.Text = 'Root Chord';
            app.SemiSpanExposedLabel_2 = uilabel(app.HorizontalTailTab);
            app.SemiSpanExposedLabel_2.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_3 = uilabel(app.HorizontalTailTab);
            app.SemiSpanTheoreticalLabel_3.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_3 = uilabel(app.HorizontalTailTab);
            app.SweepAngleLabel_3.Text = 'Sweep Angle';
            app.ChordRootLabel_10 = uilabel(app.HorizontalTailTab);
            app.ChordRootLabel_10.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_3 = uilabel(app.HorizontalTailTab);
            app.TwistanglenegativeLErotateddownLabel_3.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_3 = uilabel(app.HorizontalTailTab);
            app.DihedralAngleLabel_3.Text = 'Dihedral Angle';
            app.CHRDTPEditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHRDTPEditField_2Label.Text = 'CHRDTP';
            app.CHRDTPEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHRDTPEditField_2.Value = 1.7;
            app.SSPNEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SSPNEditField_2Label.Text = 'SSPN';
            app.SSPNEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SSPNEditField_2.Value = 10.535;
            app.SSPNEEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SSPNEEditField_2Label.Text = 'SSPNE';
            app.SSPNEEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SSPNEEditField_2.Value = 10.535;
            app.SAVSIEditField_2Label = uilabel(app.HorizontalTailTab);
            app.SAVSIEditField_2Label.Text = 'SAVSI';
            app.SAVSIEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.SAVSIEditField_2.Value = 30;
            app.CHSTATEditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHSTATEditField_2Label.Text = 'CHSTAT';
            app.CHSTATEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHSTATEditField_2.Value = 0.5;
            app.DHDADIEditField_2Label = uilabel(app.HorizontalTailTab);
            app.DHDADIEditField_2Label.Text = 'DHDADI';
            app.DHDADIEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.TWISTAEditField_2Label = uilabel(app.HorizontalTailTab);
            app.TWISTAEditField_2Label.Text = 'TWISTA';
            app.TWISTAEditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHRDREditField_2 = uieditfield(app.HorizontalTailTab, 'numeric');
            app.CHRDREditField_2.Value = 6;
            app.CHRDREditField_2Label = uilabel(app.HorizontalTailTab);
            app.CHRDREditField_2Label.Text = 'CHRDR';
            app.SaveHorizontalTailDataButton = uibutton(app.HorizontalTailTab, 'push');
            app.SaveHorizontalTailDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHorizontalTailDataButtonPushed, true);
            app.SaveHorizontalTailDataButton.Text = 'Save Horizontal Tail Data';
            app.HorizontalTailTypeButtonGroup = uibuttongroup(app.HorizontalTailTab);
            app.HorizontalTailTypeButtonGroup.Title = 'Horizontal Tail Type';
            app.straighttaperedplatformButton_2 = uiradiobutton(app.HorizontalTailTypeButtonGroup);
            app.straighttaperedplatformButton_2.Text = 'straight tapered platform';
            app.straighttaperedplatformButton_2.Value = true;
            app.doubledeltaplatformAR3Button_2 = uiradiobutton(app.HorizontalTailTypeButtonGroup);
            app.doubledeltaplatformAR3Button_2.Text = 'double delta platform AR<3';
            app.CrankedplatformAR3Button_2 = uiradiobutton(app.HorizontalTailTypeButtonGroup);
            app.CrankedplatformAR3Button_2.Text = 'Cranked platform AR>3';
            app.WriteairfoilnamelikeNACAH40012Label = uilabel(app.HorizontalTailTab);
            app.WriteairfoilnamelikeNACAH40012Label.Text = 'Write airfoil name like: ''NACA-H-4-0012''';
            app.AirfoilEditField_4Label = uilabel(app.HorizontalTailTab);
            app.AirfoilEditField_4Label.Text = 'Airfoil';
            app.AirfoilEditField_4 = uieditfield(app.HorizontalTailTab, 'text');
            app.AirfoilEditField_4.Value = 'NACA-H-4-0012';
            app.Image_7 = uiimage(app.HorizontalTailTab);
            app.Image_7.ImageSource = 'phlogo.png';
            app.VerticalTailTab = uitab(app.TabGroup);
            app.VerticalTailTab.Title = 'Vertical Tail';
            app.TipChordLabel_3 = uilabel(app.VerticalTailTab);
            app.TipChordLabel_3.Text = 'Tip Chord';
            app.RootChordLabel_3 = uilabel(app.VerticalTailTab);
            app.RootChordLabel_3.Text = 'Root Chord';
            app.SemiSpanExposedLabel_3 = uilabel(app.VerticalTailTab);
            app.SemiSpanExposedLabel_3.Text = 'Semi Span (Exposed)';
            app.SemiSpanTheoreticalLabel_4 = uilabel(app.VerticalTailTab);
            app.SemiSpanTheoreticalLabel_4.Text = 'Semi Span (Theoretical)';
            app.SweepAngleLabel_4 = uilabel(app.VerticalTailTab);
            app.SweepAngleLabel_4.Text = 'Sweep Angle';
            app.ChordRootLabel_12 = uilabel(app.VerticalTailTab);
            app.ChordRootLabel_12.Text = 'Reference chord station for inboard and outboard panel sweep angles';
            app.TwistanglenegativeLErotateddownLabel_4 = uilabel(app.VerticalTailTab);
            app.TwistanglenegativeLErotateddownLabel_4.Text = 'Twist angle (negative L.E rotated down)';
            app.DihedralAngleLabel_4 = uilabel(app.VerticalTailTab);
            app.DihedralAngleLabel_4.Text = 'Dihedral Angle';
            app.CHRDTPEditField_3Label = uilabel(app.VerticalTailTab);
            app.CHRDTPEditField_3Label.Text = 'CHRDTP';
            app.CHRDTPEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHRDTPEditField_3.Value = 3.44;
            app.SSPNEditField_3Label = uilabel(app.VerticalTailTab);
            app.SSPNEditField_3Label.Text = 'SSPN';
            app.SSPNEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SSPNEditField_3.Value = 10.5;
            app.SSPNEEditField_3Label = uilabel(app.VerticalTailTab);
            app.SSPNEEditField_3Label.Text = 'SSPNE';
            app.SSPNEEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SSPNEEditField_3.Value = 9.2;
            app.SAVSIEditField_3Label = uilabel(app.VerticalTailTab);
            app.SAVSIEditField_3Label.Text = 'SAVSI';
            app.SAVSIEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.SAVSIEditField_3.Value = 38;
            app.CHSTATEditField_3Label = uilabel(app.VerticalTailTab);
            app.CHSTATEditField_3Label.Text = 'CHSTAT';
            app.CHSTATEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHSTATEditField_3.Value = 0.5;
            app.DHDADIEditField_3Label = uilabel(app.VerticalTailTab);
            app.DHDADIEditField_3Label.Text = 'DHDADI';
            app.DHDADIEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.TWISTAEditField_3Label = uilabel(app.VerticalTailTab);
            app.TWISTAEditField_3Label.Text = 'TWISTA';
            app.TWISTAEditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHRDREditField_3 = uieditfield(app.VerticalTailTab, 'numeric');
            app.CHRDREditField_3.Value = 12.29;
            app.CHRDREditField_3Label = uilabel(app.VerticalTailTab);
            app.CHRDREditField_3Label.Text = 'CHRDR';
            app.SaveVerticalTailDataButton = uibutton(app.VerticalTailTab, 'push');
            app.SaveVerticalTailDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVerticalTailDataButtonPushed, true);
            app.SaveVerticalTailDataButton.Text = 'Save Vertical Tail Data';
            app.VerticalTailTypeButtonGroup = uibuttongroup(app.VerticalTailTab);
            app.VerticalTailTypeButtonGroup.Title = 'Vertical Tail Type';
            app.straighttaperedplatformButton_3 = uiradiobutton(app.VerticalTailTypeButtonGroup);
            app.straighttaperedplatformButton_3.Text = 'straight tapered platform';
            app.straighttaperedplatformButton_3.Value = true;
            app.doubledeltaplatformAR3Button_3 = uiradiobutton(app.VerticalTailTypeButtonGroup);
            app.doubledeltaplatformAR3Button_3.Text = 'double delta platform AR<3';
            app.CrankedplatformAR3Button_3 = uiradiobutton(app.VerticalTailTypeButtonGroup);
            app.CrankedplatformAR3Button_3.Text = 'Cranked platform AR>3';
            app.WriteairfoilnamelikeNACAV40012Label_2 = uilabel(app.VerticalTailTab);
            app.WriteairfoilnamelikeNACAV40012Label_2.Text = 'Write airfoil name like: ''NACA-V-4-0012''';
            app.AirfoilEditField_3Label = uilabel(app.VerticalTailTab);
            app.AirfoilEditField_3Label.Text = 'Airfoil';
            app.AirfoilEditField_3 = uieditfield(app.VerticalTailTab, 'text');
            app.AirfoilEditField_3.Value = 'NACA-V-4-0012';
            app.Image_8 = uiimage(app.VerticalTailTab);
            app.Image_8.ImageSource = 'phlogo.png';
            app.RunDATCOMTab = uitab(app.TabGroup);
            app.RunDATCOMTab.Title = 'Run DATCOM';
            app.MakeDtacomFileButton = uibutton(app.RunDATCOMTab, 'push');
            app.MakeDtacomFileButton.ButtonPushedFcn = createCallbackFcn(app, @MakeDtacomFileButtonPushed, true);
            app.MakeDtacomFileButton.Text = 'Make Dtacom File';
            app.RunDATCOMButton = uibutton(app.RunDATCOMTab, 'push');
            app.RunDATCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RunDATCOMButtonPushed, true);
            app.RunDATCOMButton.Text = 'Run DATCOM';
            app.Image_9 = uiimage(app.RunDATCOMTab);
            app.Image_9.ImageSource = 'phlogo.png';
            app.Image_10 = uiimage(app.RunDATCOMTab);
            app.Image_10.ImageSource = 'logo-en.png';
            app.FirstmakeDTACOMfilethenrunthefileLabel = uilabel(app.RunDATCOMTab);
            app.FirstmakeDTACOMfilethenrunthefileLabel.Text = 'First make DTACOM file then run the file.';
            app.NoteforchangebodyshapeeditBodypyLabel = uilabel(app.RunDATCOMTab);
            app.NoteforchangebodyshapeeditBodypyLabel.Text = 'Note: for change body shape edit Body.py';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DATCOM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end