% description: This app developed by Ali BaniAsad for educational purpose. This app have MIT license so be free to use it ;).
classdef TrimDiagramApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AircraftsTab                   matlab.ui.container.Tab
        DataPanel                      matlab.ui.container.Panel
        ClzeroEditFieldLabel           matlab.ui.control.Label
        ClzeroEditField                matlab.ui.control.NumericEditField
        ClEditFieldLabel               matlab.ui.control.Label
        ClEditField                    matlab.ui.control.NumericEditField
        CmEditFieldLabel               matlab.ui.control.Label
        CmEditField                    matlab.ui.control.NumericEditField
        CldeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CldeltaelevatorEditField       matlab.ui.control.NumericEditField
        CmdeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CmdeltaelevatorEditField       matlab.ui.control.NumericEditField
        CmzeroEditFieldLabel           matlab.ui.control.Label
        CmzeroEditField                matlab.ui.control.NumericEditField
        CliHEditFieldLabel             matlab.ui.control.Label
        CliHEditField                  matlab.ui.control.NumericEditField
        CmiHEditFieldLabel             matlab.ui.control.Label
        CmiHEditField                  matlab.ui.control.NumericEditField
        iHEditFieldLabel               matlab.ui.control.Label
        iHEditField                    matlab.ui.control.NumericEditField
        XcgEditFieldLabel              matlab.ui.control.Label
        XcgEditField                   matlab.ui.control.NumericEditField
        Boeing747                      matlab.ui.control.Image
        T37                            matlab.ui.control.Image
        C620                           matlab.ui.control.Image
        Beech                          matlab.ui.control.Image
        F4                             matlab.ui.control.Image
        SIAI                           matlab.ui.control.Image
        Lear                           matlab.ui.control.Image
        C182                           matlab.ui.control.Image
        C310                           matlab.ui.control.Image
        F104                           matlab.ui.control.Image
        Image11                        matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        InfoPanel                      matlab.ui.container.Panel
        TeacherDrHadiZareLabel         matlab.ui.control.Label
        StudentAliBaniAsadLabel        matlab.ui.control.Label
        StudentMohammadAbdossamadiLabel  matlab.ui.control.Label
        alibaniasad1999yahoocomLabel   matlab.ui.control.Label
        mimabdossamadigmailcomLabel    matlab.ui.control.Label
        SeeourcodeingithubLabel        matlab.ui.control.Label
        githubcomalibaniasad1999Label  matlab.ui.control.Label
        AircraftsButtonGroup           matlab.ui.container.ButtonGroup
        Boeing747lowcruiseButton       matlab.ui.control.RadioButton
        Boeing747highcruiseButton      matlab.ui.control.RadioButton
        SIAIMarchettiS211lowcruiseButton  matlab.ui.control.RadioButton
        SIAIMarchettiS211highcruiseButton  matlab.ui.control.RadioButton
        LockheedF104cruiseButton       matlab.ui.control.RadioButton
        LearJetMaxweightcruiseButton   matlab.ui.control.RadioButton
        LearJetLowweightcruiseButton   matlab.ui.control.RadioButton
        McdonnellDouglasF4supersoniccruiseButton  matlab.ui.control.RadioButton
        McdonnellDouglasF4subsoniccruiseButton  matlab.ui.control.RadioButton
        CessnaT37AcruiseButton         matlab.ui.control.RadioButton
        Cessna620cruiseButton          matlab.ui.control.RadioButton
        Cessna310cruiseButton          matlab.ui.control.RadioButton
        Cessna182cruiseButton          matlab.ui.control.RadioButton
        Beech99lowcruiseButton         matlab.ui.control.RadioButton
        Beech99highcruiseButton        matlab.ui.control.RadioButton
        Phoenix50Button                matlab.ui.control.RadioButton
        Phoenix76Button                matlab.ui.control.RadioButton
        TrimDiagramTab                 matlab.ui.container.Tab
        FigurePanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        ControlCardsPanel              matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        mostaftCGSwitchLabel           matlab.ui.control.Label
        mostaftCGSwitch                matlab.ui.control.Switch
        mostforwardCGSwitchLabel       matlab.ui.control.Label
        mostforwardCGSwitch            matlab.ui.control.Switch
        ForwardCGKnobLabel             matlab.ui.control.Label
        ForwardCGKnob                  matlab.ui.control.Knob
        AftCGKnobLabel                 matlab.ui.control.Label
        AftCGKnob                      matlab.ui.control.Knob
        CGpositionSliderLabel          matlab.ui.control.Label
        CGpositionSlider               matlab.ui.control.Slider
        MaximumAlphaSwitchLabel        matlab.ui.control.Label
        MaximumAlphaSwitch             matlab.ui.control.Switch
        MaximumAlphaSliderLabel        matlab.ui.control.Label
        MaximumAlphaSlider             matlab.ui.control.Slider
        CGSliderSwitchLabel            matlab.ui.control.Label
        CGSliderSwitch                 matlab.ui.control.Switch
    end

    
    methods (Access = private)
                
        function TrimCurveInit(app)
            app.CmzeroEditField.Value = 0; % distance from real cg , delta_x_cg = now cg - cg
        end
        function TrimPloter(app)
            %%% iH %%%
            iH = app.iHEditField.Value;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = app.CliHEditField.Value; % Rad
            %%% Cm iH %%%
            Cm_iH = app.CmiHEditField.Value;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha) +...
                (app.CGpositionSlider.Value - app.XcgEditField.Value);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            %%% x_cg_bar
            %x_cg = app.XcgEditField.Value;
            delta_elevator = -30:5:15; %deg
            delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:3;
            hold(app.UIAxes,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + Cm_alpha_cl_alph * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes,Cm,Cl)
                hold(app.UIAxes,"on");
            end
            set(app.UIAxes, 'xdir', 'reverse' );
            xlabel(app.UIAxes, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes, '$C_l$','Interpreter','latex');
            legend(app.UIAxes, '$\delta_e = -30^{\circ}$','$\delta_e = -25^{\circ}$',...
        '$\delta_e = -20^{\circ}$','$\delta_e = -15^{\circ}$','$\delta_e = -10^{\circ}$','$\delta_e = -5^{\circ}$',...
        '$\delta_e = 0^{\circ}$','$\delta_e = 5^{\circ}$',...
        '$\delta_e = 10^{\circ}$','$\delta_e = 15^{\circ}$',...
        'Interpreter','latex')
        end
        function Trimploter_cg(app)
            %%% iH %%%
            iH = app.iHEditField.Value;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = app.CliHEditField.Value; % Rad
            %%% Cm iH %%%
            Cm_iH = app.CmiHEditField.Value;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            %%% x_cg_bar
            x_cg = app.XcgEditField.Value;
            delta_elevator = -30:5:15; %deg
            delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:3;
            hold(app.UIAxes_2,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes_2, Cm,Cl)
                hold(app.UIAxes_2, "on");
            end
            if app.CGSliderSwitch.Value == "On"
                x_axis = -10:.1:10;
                if x_cg == app.CGpositionSlider.Value
                    Cm_equal = zeros(length(x_axis));
                    plot(app.UIAxes_2, Cm_equal, x_axis, 'k', 'linewidth', 2);
                else
                    y1 = 1 / (x_cg - app.CGpositionSlider.Value) * x_axis;
                    plot(app.UIAxes_2, x_axis, y1, 'k', 'linewidth', 2);
                end
                
            end            
            set(app.UIAxes_2, 'xdir', 'reverse' );
            xlabel(app.UIAxes_2, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes_2, '$C_l$','Interpreter','latex');
            if app.mostaftCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.AftCGKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r:', 'linewidth', 2);
            end
            if app.mostforwardCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.ForwardCGKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r-', 'linewidth', 2);
            end
            if app.MaximumAlphaSwitch.Value == "On"
                alpha = app.MaximumAlphaSlider.Value;
                stall_alpha = alpha * pi / 180; % degree to rad
                Cl_stall = zeros(1);
                Cm_stall = zeros(1);
                for i = 1:length(delta_elevator)
                    Cl_stall(i) = Cl_zero + Cl_alpha * stall_alpha +...
                        Cl_delta_elevator * delta_elevator(i);
                    Cm_stall(i) = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl_stall(i) +...
                        Cm_delta_elevator_bar * delta_elevator(i);
                end
                Stall_line = fit(Cm_stall', Cl_stall', 'poly1');
                Cm_stall_axis = -1:0.01:1;
                plot(app.UIAxes_2, Cm_stall_axis, Stall_line.p1 * Cm_stall_axis + Stall_line.p2, 'k:', 'linewidth', 2);
            end
            axis(app.UIAxes_2, [-1 1 0 2])
        end
        function vis(app)
            app.mostforwardCGSwitch;
        end
        function off_all_box(app)
            app.Boeing747highcruiseCheckBox.Value = 0;
            app.Boeing747lowcruiseCheckBox.Value = 0;
            app.SIAIMarchettiS211highcruiseCheckBox.Value = 0;
            app.SIAIMarchettiS211lowcruiseCheckBox.Value = 0;
            app.McdonnellDouglasF4subsoniccruiseCheckBox.Value = 0;
            app.McdonnellDouglasF4supersoniccruiseCheckBox.Value = 0;
            app.LockheedF104cruiseCheckBox.Value = 0;
            app.LearJetLowweightcruiseCheckBox.Value = 0;
            app.LearJetMaxweightcruiseCheckBox.Value = 0;
            app.Cessna182cruiseCheckBox.Value = 0;
            app.Cessna310cruiseCheckBox.Value = 0;
            app.Cessna620cruiseCheckBox.Value = 0;
            app.Beech99highcruiseCheckBox.Value = 0;
            app.Beech99lowcruiseCheckBox.Value = 0;
            app.CessnaT37AcruiseCheckBox.Value = 0;
        end
        function off_all_photo(app)
            app.Boeing747.Visible = 'off';
            app.F104.Visible = 'off';
            app.C310.Visible = 'off';
            app.C182.Visible = 'off';
            app.Lear.Visible = 'off';
            app.SIAI.Visible = 'off';
            app.F4.Visible = 'off';
            app.Beech.Visible = 'off';
            app.C620.Visible = 'off';
            app.T37.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.mostforwardCGSwitch.Value == "Off"
                app.ForwardCGKnob.Visible = "off";
                app.ForwardCGKnobLabel.Visible = "off";
            end
            if app.mostaftCGSwitch.Value == "Off"
                app.AftCGKnob.Visible = "off";
                app.AftCGKnobLabel.Visible = "off";
            end
            app.off_all_photo;
            if app.MaximumAlphaSwitch.Value == "Off"
                app.MaximumAlphaSlider.Visible = "off";
                app.MaximumAlphaSliderLabel.Visible = "off";
            end
            if app.CGSliderSwitch.Value == "Off"
                app.Image.Visible = "off";
                app.CGpositionSlider.Visible = "off";
                app.CGpositionSliderLabel.Visible = "off";
            end
            selectedButton = app.AircraftsButtonGroup.SelectedObject.Text;
            if selectedButton == "Boeing 747 low cruise"
                app.off_all_photo;
                app.Boeing747.Visible = 'on';
                seven_four_seven_low_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg; 
            end
        end

        % Callback function
        function Boeing747lowcruiseButtonPushed(app, event)
            seven_four_seven_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Value changed function: CGpositionSlider
        function CGpositionSliderValueChanged(app, event)
            app.TrimPloter;
            app.Trimploter_cg;
        end

        % Value changed function: ForwardCGKnob
        function ForwardCGKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Value changing function: ForwardCGKnob
        function ForwardCGKnobValueChanging(app, event)
            
        end

        % Value changed function: mostforwardCGSwitch
        function mostforwardCGSwitchValueChanged(app, event)
            value = app.mostforwardCGSwitch.Value;
            if value == "Off"
                app.ForwardCGKnob.Visible = "off";
                app.ForwardCGKnobLabel.Visible = "off";
            else
                app.ForwardCGKnob.Visible = "on";
                app.ForwardCGKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changed function: mostaftCGSwitch
        function mostaftCGSwitchValueChanged(app, event)
            value = app.mostaftCGSwitch.Value;
            if value == "Off"
                app.AftCGKnob.Visible = "off";
                app.AftCGKnobLabel.Visible = "off";
            else
                app.AftCGKnob.Visible = "on";
                app.AftCGKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changing function: CGpositionSlider
        function CGpositionSliderValueChanging(app, event)
            
        end

        % Value changed function: AftCGKnob
        function AftCGKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Callback function
        function SIAIMarchettiS211lowcruiseButtonPushed(app, event)
            SIAI_MarchettiS211_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function SIAIMarchettiS211highcruiseButtonPushed(app, event)
            SIAI_MarchettiS211_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function McdonnellDouglasF4supersoniccruiseButtonPushed(app, event)
            Mcdonnell_Douglas_F4_supersonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function McdonnellDouglasF4subsoniccruiseButtonPushed(app, event)
            Mcdonnell_Douglas_F4_subsonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LockheedF104cruiseButtonPushed(app, event)
            Lockheed_F104_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LearJetMaxweightcruiseButtonPushed(app, event)
            LearJet_Max_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LearJetLowweightcruiseButtonPushed(app, event)
            LearJet_Low_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function CessnaT37AcruiseButtonPushed(app, event)
            Cessna_T37_A_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna620cruiseButtonPushed(app, event)
            Cessna_620_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna310cruiseButtonPushed(app, event)
            Cessna_310_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna182cruiseButtonPushed(app, event)
            Cessna_182_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Boeing747highcruiseButtonPushed(app, event)
            Boeing_747_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Beech99lowcruiseButtonPushed(app, event)
            Beech_99_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Beech99highcruiseButtonPushed(app, event)
            Beech_99_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function McdonnellDouglasF4supersoniccruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.McdonnellDouglasF4supersoniccruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.F4.Visible = 'on';
            Mcdonnell_Douglas_F4_subsonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Boeing747highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Boeing747highcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Boeing747.Visible = 'on';
            Boeing_747_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Boeing747lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Boeing747lowcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Boeing747.Visible = 'on';
            seven_four_seven_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function SIAIMarchettiS211lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.SIAIMarchettiS211lowcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.SIAI.Visible = 'on';
            SIAI_MarchettiS211_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function SIAIMarchettiS211highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.SIAIMarchettiS211highcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.SIAI.Visible = 'on';
            SIAI_MarchettiS211_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LockheedF104cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LockheedF104cruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.F104.Visible = 'on';
            Lockheed_F104_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LearJetMaxweightcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LearJetMaxweightcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Lear.Visible = 'on';
            LearJet_Max_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function LearJetLowweightcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.LearJetLowweightcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Lear.Visible = 'on';
            LearJet_Low_weight_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function McdonnellDouglasF4subsoniccruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.McdonnellDouglasF4subsoniccruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.F4.Visible = 'on';
            Mcdonnell_Douglas_F4_subsonic_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function CessnaT37AcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.CessnaT37AcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.T37.Visible = 'on';
            Cessna_T37_A_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna620cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna620cruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.C620.Visible = 'on';
            Cessna_620_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna310cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna310cruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.C310.Visible = 'on';
            Cessna_310_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Cessna182cruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Cessna182cruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.C182.Visible = 'on';
            Cessna_182_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Beech99lowcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Beech99lowcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Beech.Visible = 'on';
            Beech_99_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Callback function
        function Beech99highcruiseCheckBoxValueChanged(app, event)
            app.off_all_box;
            app.Beech99highcruiseCheckBox.Value = 1;
            app.off_all_photo;
            app.Beech.Visible = 'on';
            Beech_99_high_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Size changed function: DataPanel
        function DataPanelSizeChanged(app, event)
            %position = app.DataPanel.Position;
            
        end

        % Value changed function: CmzeroEditField
        function CmzeroEditFieldValueChanged(app, event)
            %value = app.CmzeroEditField.Value;
            
        end

        % Value changed function: MaximumAlphaSwitch
        function MaximumAlphaSwitchValueChanged(app, event)
            value = app.MaximumAlphaSwitch.Value;
            if value == "Off"
                app.MaximumAlphaSlider.Visible = "off";
                app.MaximumAlphaSliderLabel.Visible = "off";
            else
                app.MaximumAlphaSlider.Visible = "on";
                app.MaximumAlphaSliderLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changed function: MaximumAlphaSlider
        function MaximumAlphaSliderValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Value changed function: CGSliderSwitch
        function CGSliderSwitchValueChanged(app, event)

            value = app.CGSliderSwitch.Value;
            if value == "Off"
                app.Image.Visible = "off";
                app.CGpositionSlider.Visible = "off";
                app.CGpositionSliderLabel.Visible = "off";
            else
                app.Image.Visible = "on";
                app.CGpositionSlider.Visible = "on";
                app.CGpositionSliderLabel.Visible = "on";
                app.CGpositionSlider.Value = app.XcgEditField.Value;
            end
            app.TrimPloter;
            app.Trimploter_cg;
        end

        % Selection changed function: AircraftsButtonGroup
        function AircraftsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AircraftsButtonGroup.SelectedObject.Text;
            if selectedButton == "Boeing 747 low cruise"
                app.off_all_photo;
                app.Boeing747.Visible = 'on';
                seven_four_seven_low_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg; 
            elseif selectedButton == "Phoenix 50"
                app.off_all_photo;
                %app.Boeing747.Visible = 'on';
                make_data;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Boeing 747 high cruise"
                app.off_all_photo;
                app.Boeing747.Visible = 'on';
                Boeing_747_high_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "SIAI MarchettiS211 low cruise"
                app.off_all_photo;
                app.SIAI.Visible = 'on';
                SIAI_MarchettiS211_low_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "SIAI MarchettiS211 high cruise"
                app.off_all_photo;
                app.SIAI.Visible = 'on';
                SIAI_MarchettiS211_high_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Lockheed F104 cruise"
                app.off_all_photo;
                app.F104.Visible = 'on';
                Lockheed_F104_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "LearJet Max weight cruise"
                app.off_all_photo;
                app.Lear.Visible = 'on';
                LearJet_Max_weight_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "LearJet Low weight cruise"
                app.off_all_photo;
                app.Lear.Visible = 'on';
                LearJet_Low_weight_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Mcdonnell Douglas F4 supersonic cruise"
                app.off_all_photo;
                app.F4.Visible = 'on';
                Mcdonnell_Douglas_F4_supersonic_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Mcdonnell Douglas F4 subsonic cruise"
                app.off_all_photo;
                app.F4.Visible = 'on';
                Mcdonnell_Douglas_F4_subsonic_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Cessna T37 A cruise"
                app.off_all_photo;
                app.T37.Visible = 'on';
                Cessna_T37_A_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Cessna 620 cruise"
                app.off_all_photo;
                app.C620.Visible = 'on';
                Cessna_620_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Cessna 310 cruise"
                app.off_all_photo;
                app.C310.Visible = 'on';
                Cessna_310_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Cessna 182 cruise"
                app.off_all_photo;
                app.C182.Visible = 'on';
                Cessna_182_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Beech 99 low cruise"
                app.off_all_photo;
                app.Beech.Visible = 'on';
                Beech_99_low_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            elseif selectedButton == "Beech 99 high cruise"
                app.off_all_photo;
                app.Beech.Visible = 'on';
                Beech_99_high_cruise;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                app.CliHEditField.Value            = Cl_iH;
                app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftsTab = uitab(app.TabGroup);
            app.AircraftsTab.Title = 'Aircrafts';
            app.DataPanel = uipanel(app.AircraftsTab);
            app.DataPanel.Title = 'Data';
            app.DataPanel.SizeChangedFcn = createCallbackFcn(app, @DataPanelSizeChanged, true);
            app.ClzeroEditFieldLabel = uilabel(app.DataPanel);
            app.ClzeroEditFieldLabel.Text = 'Cl zero';
            app.ClzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.ClEditFieldLabel = uilabel(app.DataPanel);
            app.ClEditFieldLabel.Text = 'Cl α';
            app.ClEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmEditFieldLabel = uilabel(app.DataPanel);
            app.CmEditFieldLabel.Text = 'Cm α';
            app.CmEditField = uieditfield(app.DataPanel, 'numeric');
            app.CldeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CldeltaelevatorEditFieldLabel.Text = 'Cl delta elevator';
            app.CldeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmdeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CmdeltaelevatorEditFieldLabel.Text = 'Cm delta elevator';
            app.CmdeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditFieldLabel = uilabel(app.DataPanel);
            app.CmzeroEditFieldLabel.Text = 'Cm zero';
            app.CmzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditField.ValueChangedFcn = createCallbackFcn(app, @CmzeroEditFieldValueChanged, true);
            app.CliHEditFieldLabel = uilabel(app.DataPanel);
            app.CliHEditFieldLabel.Text = 'Cl i H';
            app.CliHEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmiHEditFieldLabel = uilabel(app.DataPanel);
            app.CmiHEditFieldLabel.Text = 'Cm i H';
            app.CmiHEditField = uieditfield(app.DataPanel, 'numeric');
            app.iHEditFieldLabel = uilabel(app.DataPanel);
            app.iHEditFieldLabel.Text = 'i H';
            app.iHEditField = uieditfield(app.DataPanel, 'numeric');
            app.XcgEditFieldLabel = uilabel(app.DataPanel);
            app.XcgEditFieldLabel.Text = 'X cg';
            app.XcgEditField = uieditfield(app.DataPanel, 'numeric');
            app.Boeing747 = uiimage(app.AircraftsTab);
            app.Boeing747.ImageSource = 'ea2d745a970680c7efd6f4a0cf6ba642df7a46d1_boeing-747-400-privatefly-aa1616.jpg';
            app.T37 = uiimage(app.AircraftsTab);
            app.T37.ImageSource = 'T-37_021203-O-9999G-003.jpg';
            app.C620 = uiimage(app.AircraftsTab);
            app.C620.ImageSource = '511L.jpg';
            app.Beech = uiimage(app.AircraftsTab);
            app.Beech.ImageSource = 'Jamaica_Air_Shuttle_Beechcraft_Model_99_In_Flight.jpg';
            app.F4 = uiimage(app.AircraftsTab);
            app.F4.ImageSource = 'QF-4_Holloman_AFB.jpg';
            app.SIAI = uiimage(app.AircraftsTab);
            app.SIAI.ImageSource = 'Privately_owned_S.211_N852TC_in_flight.jpg';
            app.Lear = uiimage(app.AircraftsTab);
            app.Lear.ImageSource = 'learjet0216.jpg';
            app.C182 = uiimage(app.AircraftsTab);
            app.C182.ImageSource = '03-333_146.jpg';
            app.C310 = uiimage(app.AircraftsTab);
            app.C310.ImageSource = '2004p_310_001_16x9.jpg';
            app.F104 = uiimage(app.AircraftsTab);
            app.F104.ImageSource = '4UVG2DNI6REB3N46F7HABKSA5U.jpg';
            app.Image11 = uiimage(app.AircraftsTab);
            app.Image11.ImageSource = 'logo-en.png';
            app.Image12 = uiimage(app.AircraftsTab);
            app.Image12.ImageSource = 'IMG_1075.jpg';
            app.InfoPanel = uipanel(app.AircraftsTab);
            app.InfoPanel.Title = 'Info';
            app.TeacherDrHadiZareLabel = uilabel(app.InfoPanel);
            app.TeacherDrHadiZareLabel.Text = 'Teacher: Dr. Hadi Zare';
            app.StudentAliBaniAsadLabel = uilabel(app.InfoPanel);
            app.StudentAliBaniAsadLabel.Text = 'Student: Ali BaniAsad';
            app.StudentMohammadAbdossamadiLabel = uilabel(app.InfoPanel);
            app.StudentMohammadAbdossamadiLabel.Text = 'Student: Mohammad Abdossamadi';
            app.alibaniasad1999yahoocomLabel = uilabel(app.InfoPanel);
            app.alibaniasad1999yahoocomLabel.Text = 'alibaniasad1999@yahoo.com';
            app.mimabdossamadigmailcomLabel = uilabel(app.InfoPanel);
            app.mimabdossamadigmailcomLabel.Text = 'mimabdossamadi@gmail.com';
            app.SeeourcodeingithubLabel = uilabel(app.InfoPanel);
            app.SeeourcodeingithubLabel.Text = 'See our code in github:';
            app.githubcomalibaniasad1999Label = uilabel(app.InfoPanel);
            app.githubcomalibaniasad1999Label.Text = 'github.com/alibaniasad1999';
            app.AircraftsButtonGroup = uibuttongroup(app.AircraftsTab);
            app.AircraftsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AircraftsButtonGroupSelectionChanged, true);
            app.AircraftsButtonGroup.Title = 'Aircrafts';
            app.Boeing747lowcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Boeing747lowcruiseButton.Text = 'Boeing 747 low cruise';
            app.Boeing747lowcruiseButton.Value = true;
            app.Boeing747highcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Boeing747highcruiseButton.Text = 'Boeing 747 high cruise';
            app.SIAIMarchettiS211lowcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.SIAIMarchettiS211lowcruiseButton.Text = 'SIAI MarchettiS211 low cruise';
            app.SIAIMarchettiS211highcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.SIAIMarchettiS211highcruiseButton.Text = 'SIAI MarchettiS211 high cruise';
            app.LockheedF104cruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.LockheedF104cruiseButton.Text = 'Lockheed F104 cruise';
            app.LearJetMaxweightcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.LearJetMaxweightcruiseButton.Text = 'LearJet Max weight cruise';
            app.LearJetLowweightcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.LearJetLowweightcruiseButton.Text = 'LearJet Low weight cruise';
            app.McdonnellDouglasF4supersoniccruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.McdonnellDouglasF4supersoniccruiseButton.Text = 'Mcdonnell Douglas F4 supersonic cruise';
            app.McdonnellDouglasF4subsoniccruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.McdonnellDouglasF4subsoniccruiseButton.Text = 'Mcdonnell Douglas F4 subsonic cruise';
            app.CessnaT37AcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.CessnaT37AcruiseButton.Text = 'Cessna T37 A cruise';
            app.Cessna620cruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Cessna620cruiseButton.Text = 'Cessna 620 cruise';
            app.Cessna310cruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Cessna310cruiseButton.Text = 'Cessna 310 cruise';
            app.Cessna182cruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Cessna182cruiseButton.Text = 'Cessna 182 cruise';
            app.Beech99lowcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Beech99lowcruiseButton.Text = 'Beech 99 low cruise';
            app.Beech99highcruiseButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Beech99highcruiseButton.Text = 'Beech 99 high cruise';
            app.Phoenix50Button = uiradiobutton(app.AircraftsButtonGroup);
            app.Phoenix50Button.Text = 'Phoenix 50';
            app.Phoenix76Button = uiradiobutton(app.AircraftsButtonGroup);
            app.Phoenix76Button.Text = 'Phoenix 76';
            app.TrimDiagramTab = uitab(app.TabGroup);
            app.TrimDiagramTab.Title = 'Trim Diagram';
            app.FigurePanel = uipanel(app.TrimDiagramTab);
            app.FigurePanel.Title = 'Figure';
            app.UIAxes = uiaxes(app.FigurePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2 = uiaxes(app.FigurePanel);
            title(app.UIAxes_2, 'x')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ControlCardsPanel = uipanel(app.TrimDiagramTab);
            app.ControlCardsPanel.Title = 'Control Cards';
            app.Image = uiimage(app.ControlCardsPanel);
            app.Image.ImageSource = 'Screen Shot 1400-03-17 at 14.48.52.png';
            app.mostaftCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostaftCGSwitchLabel.Text = 'most aft CG';
            app.mostaftCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostaftCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostaftCGSwitchValueChanged, true);
            app.mostforwardCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostforwardCGSwitchLabel.Text = 'most forward CG';
            app.mostforwardCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostforwardCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostforwardCGSwitchValueChanged, true);
            app.ForwardCGKnobLabel = uilabel(app.ControlCardsPanel);
            app.ForwardCGKnobLabel.Text = 'Forward CG';
            app.ForwardCGKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.ForwardCGKnob.Limits = [0 0.2];
            app.ForwardCGKnob.ValueChangedFcn = createCallbackFcn(app, @ForwardCGKnobValueChanged, true);
            app.ForwardCGKnob.ValueChangingFcn = createCallbackFcn(app, @ForwardCGKnobValueChanging, true);
            app.AftCGKnobLabel = uilabel(app.ControlCardsPanel);
            app.AftCGKnobLabel.Text = 'Aft CG';
            app.AftCGKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.AftCGKnob.Limits = [0.7 0.9];
            app.AftCGKnob.MajorTicks = [0.7 0.75 0.8 0.85 0.9];
            app.AftCGKnob.ValueChangedFcn = createCallbackFcn(app, @AftCGKnobValueChanged, true);
            app.AftCGKnob.Value = 0.7;
            app.CGpositionSliderLabel = uilabel(app.ControlCardsPanel);
            app.CGpositionSliderLabel.Text = 'CG position';
            app.CGpositionSlider = uislider(app.ControlCardsPanel);
            app.CGpositionSlider.Limits = [0 1];
            app.CGpositionSlider.ValueChangedFcn = createCallbackFcn(app, @CGpositionSliderValueChanged, true);
            app.CGpositionSlider.ValueChangingFcn = createCallbackFcn(app, @CGpositionSliderValueChanging, true);
            app.CGpositionSlider.Value = 0.25;
            app.MaximumAlphaSwitchLabel = uilabel(app.ControlCardsPanel);
            app.MaximumAlphaSwitchLabel.Text = 'Maximum Alpha';
            app.MaximumAlphaSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.MaximumAlphaSwitch.ValueChangedFcn = createCallbackFcn(app, @MaximumAlphaSwitchValueChanged, true);
            app.MaximumAlphaSliderLabel = uilabel(app.ControlCardsPanel);
            app.MaximumAlphaSliderLabel.Text = 'Maximum Alpha';
            app.MaximumAlphaSlider = uislider(app.ControlCardsPanel);
            app.MaximumAlphaSlider.Limits = [10 15];
            app.MaximumAlphaSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumAlphaSliderValueChanged, true);
            app.MaximumAlphaSlider.Value = 10;
            app.CGSliderSwitchLabel = uilabel(app.ControlCardsPanel);
            app.CGSliderSwitchLabel.Text = 'CG Slider';
            app.CGSliderSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.CGSliderSwitch.ValueChangedFcn = createCallbackFcn(app, @CGSliderSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimDiagramApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end