% description: This app developed by Ali BaniAsad for educational purpose. This app have MIT license so be free to use it ;).
classdef TrimDiagramApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AircraftsTab                   matlab.ui.container.Tab
        DataPanel                      matlab.ui.container.Panel
        ClzeroEditFieldLabel           matlab.ui.control.Label
        ClzeroEditField                matlab.ui.control.NumericEditField
        ClEditFieldLabel               matlab.ui.control.Label
        ClEditField                    matlab.ui.control.NumericEditField
        CmEditFieldLabel               matlab.ui.control.Label
        CmEditField                    matlab.ui.control.NumericEditField
        CldeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CldeltaelevatorEditField       matlab.ui.control.NumericEditField
        CmdeltaelevatorEditFieldLabel  matlab.ui.control.Label
        CmdeltaelevatorEditField       matlab.ui.control.NumericEditField
        CmzeroEditFieldLabel           matlab.ui.control.Label
        CmzeroEditField                matlab.ui.control.NumericEditField
        XcgEditFieldLabel              matlab.ui.control.Label
        XcgEditField                   matlab.ui.control.NumericEditField
        Image11                        matlab.ui.control.Image
        Image12                        matlab.ui.control.Image
        AircraftsButtonGroup           matlab.ui.container.ButtonGroup
        Phoenix50SeatButton            matlab.ui.control.RadioButton
        Phoenix76SeatButton            matlab.ui.control.RadioButton
        AirplaneDesignIILabel          matlab.ui.control.Label
        DrBanazadehLabel               matlab.ui.control.Label
        SourcecodeavailableatLabel     matlab.ui.control.Label
        githubcomalibaniasad1999AirplaneDesignIILabel  matlab.ui.control.Label
        ContactUsLabel                 matlab.ui.control.Label
        alibaniasad1999yahoocomLabel   matlab.ui.control.Label
        Image13                        matlab.ui.control.Image
        TrimDiagramTab                 matlab.ui.container.Tab
        FigurePanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        ControlCardsPanel              matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        mostaftCGSwitchLabel           matlab.ui.control.Label
        mostaftCGSwitch                matlab.ui.control.Switch
        mostforwardCGSwitchLabel       matlab.ui.control.Label
        mostforwardCGSwitch            matlab.ui.control.Switch
        ForwardCGKnobLabel             matlab.ui.control.Label
        ForwardCGKnob                  matlab.ui.control.Knob
        AftCGKnobLabel                 matlab.ui.control.Label
        AftCGKnob                      matlab.ui.control.Knob
        CGpositionSliderLabel          matlab.ui.control.Label
        CGpositionSlider               matlab.ui.control.Slider
        MaximumAlphaSwitchLabel        matlab.ui.control.Label
        MaximumAlphaSwitch             matlab.ui.control.Switch
        MaximumAlphaSliderLabel        matlab.ui.control.Label
        MaximumAlphaSlider             matlab.ui.control.Slider
        CGSliderSwitchLabel            matlab.ui.control.Label
        CGSliderSwitch                 matlab.ui.control.Switch
    end

    
    methods (Access = private)
                
        function TrimCurveInit(app)
            app.CmzeroEditField.Value = 0; % distance from real cg , delta_x_cg = now cg - cg
        end
        function TrimPloter(app)
            %%% iH %%%
            iH = 0;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = 0; % Rad
            %%% Cm iH %%%
            Cm_iH = 0;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha) +...
                (app.CGpositionSlider.Value - app.XcgEditField.Value);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            %%% x_cg_bar
            %x_cg = app.XcgEditField.Value;
            delta_elevator = -30:5:15; %deg
            %delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:1.5;
            hold(app.UIAxes,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + Cm_alpha_cl_alph * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes,Cm,Cl)
                hold(app.UIAxes,"on");
            end
            set(app.UIAxes, 'xdir', 'reverse' );
            xlabel(app.UIAxes, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes, '$C_l$','Interpreter','latex');
            legend(app.UIAxes, '$\delta_e = -30^{\circ}$','$\delta_e = -25^{\circ}$',...
        '$\delta_e = -20^{\circ}$','$\delta_e = -15^{\circ}$','$\delta_e = -10^{\circ}$','$\delta_e = -5^{\circ}$',...
        '$\delta_e = 0^{\circ}$','$\delta_e = 5^{\circ}$',...
        '$\delta_e = 10^{\circ}$','$\delta_e = 15^{\circ}$',...
        'Interpreter','latex')
        end
        function Trimploter_cg(app)
            %%% iH %%%
            iH = 0;
            %%% CL elevator %%%
            Cl_delta_elevator = app.CldeltaelevatorEditField.Value;
            %%% Cm elevator %%%
            Cm_delta_elevator = app.CmdeltaelevatorEditField.Value;
            %%% Cl zero %%%
            Cl_zero = app.ClzeroEditField.Value;
            %%% Cm zero %%%
            Cm_zero = app.CmzeroEditField.Value;
            %%% Cl alpha %%%
            Cl_alpha = app.ClEditField.Value; % Rad
            %%% Cm alpha %%%
            Cm_alpha = app.CmEditField.Value;
            %%% Cl iH %%%
            Cl_iH = 0; % Rad
            %%% Cm iH %%%
            Cm_iH = 0;
            %%% Cm_alpha_cl_alpha %%%
            Cm_alpha_cl_alph = (Cm_alpha / Cl_alpha);
            %%% Cm zero bar %%%
            Cm_zero_bar = Cm_zero - Cm_alpha_cl_alph * Cl_zero;
            %%% Cm delta elevator bar %%%
            Cm_delta_elevator_bar = Cm_delta_elevator - Cm_alpha_cl_alph * Cl_delta_elevator;
            %%% Cm iH bar %%%
            Cm_iH_bar = Cm_iH - Cm_alpha_cl_alph * Cl_iH;
            %%% x_cg_bar
            x_cg = app.XcgEditField.Value;
            delta_elevator = -30:5:15; %deg
            %delta_elevator = delta_elevator * pi / 180; %rad
            Cl = 0:.1:3;
            hold(app.UIAxes_2,"off");
            for i = 1:length(delta_elevator)
                Cm = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl +...
                Cm_delta_elevator_bar * delta_elevator(i) + Cm_iH_bar*iH;
                plot(app.UIAxes_2, Cm,Cl)
                hold(app.UIAxes_2, "on");
            end
            if app.CGSliderSwitch.Value == "On"
                x_axis = -10:.1:10;
                if x_cg == app.CGpositionSlider.Value
                    Cm_equal = zeros(length(x_axis));
                    plot(app.UIAxes_2, Cm_equal, x_axis, 'k', 'linewidth', 2);
                else
                    y1 = 1 / (x_cg - app.CGpositionSlider.Value) * x_axis;
                    plot(app.UIAxes_2, x_axis, y1, 'k', 'linewidth', 2);
                end
                
            end            
            set(app.UIAxes_2, 'xdir', 'reverse' );
            xlabel(app.UIAxes_2, '$C_m$','Interpreter','latex');
            ylabel(app.UIAxes_2, '$C_l$','Interpreter','latex');
            if app.mostaftCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.AftCGKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r:', 'linewidth', 2);
            end
            if app.mostforwardCGSwitch.Value == "On"
                x_axis = -10:.1:10;
                y1 = 1 / (x_cg - app.ForwardCGKnob.Value) * x_axis;
                plot(app.UIAxes_2, x_axis, y1, 'r-', 'linewidth', 2);
            end
            if app.MaximumAlphaSwitch.Value == "On"
                alpha = app.MaximumAlphaSlider.Value;
                stall_alpha = alpha ; % degree to rad
                Cl_stall = zeros(1);
                Cm_stall = zeros(1);
                for i = 1:length(delta_elevator)
                    Cl_stall(i) = Cl_zero + Cl_alpha * stall_alpha +...
                        Cl_delta_elevator * delta_elevator(i);
                    Cm_stall(i) = Cm_zero_bar + (Cm_alpha / Cl_alpha) * Cl_stall(i) +...
                        Cm_delta_elevator_bar * delta_elevator(i);
                end
                Stall_line = fit(Cm_stall', Cl_stall', 'poly1');
                Cm_stall_axis = -1:0.01:1;
                plot(app.UIAxes_2, Cm_stall_axis, Stall_line.p1 * Cm_stall_axis + Stall_line.p2, 'k:', 'linewidth', 2);
            end
            axis(app.UIAxes_2, [-0.4 0.4 0 1.5])
        end
        function vis(app)
            app.mostforwardCGSwitch;
        end
        function off_all_photo(app)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.mostforwardCGSwitch.Value == "Off"
                app.ForwardCGKnob.Visible = "off";
                app.ForwardCGKnobLabel.Visible = "off";
            end
            if app.mostaftCGSwitch.Value == "Off"
                app.AftCGKnob.Visible = "off";
                app.AftCGKnobLabel.Visible = "off";
            end
            app.off_all_photo;
            if app.MaximumAlphaSwitch.Value == "Off"
                app.MaximumAlphaSlider.Visible = "off";
                app.MaximumAlphaSliderLabel.Visible = "off";
            end
            if app.CGSliderSwitch.Value == "Off"
                app.Image.Visible = "off";
                app.CGpositionSlider.Visible = "off";
                app.CGpositionSliderLabel.Visible = "off";
            end
            selectedButton = app.AircraftsButtonGroup.SelectedObject.Text;
            if selectedButton == "Phoenix 50 Seat"
                app.off_all_photo;
                %app.Boeing747.Visible = 'on';
                make_data;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                %app.CliHEditField.Value            = Cl_iH;
                %app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                %app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg; 
            end
        end

        % Callback function
        function Boeing747lowcruiseButtonPushed(app, event)
            seven_four_seven_low_cruise;
            app.ClzeroEditField.Value          = Cl_zero;
            app.CmzeroEditField.Value          = Cm_zero;
            app.ClEditField.Value              = Cl_alpha;
            app.CmEditField.Value              = Cm_alpha;
            app.CliHEditField.Value            = Cl_iH;
            app.CmiHEditField.Value            = Cm_iH;
            app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
            app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
            app.iHEditField.Value              = iH;
            app.XcgEditField.Value             = x_cg;
            app.TrimPloter;
            app.Trimploter_cg;
            app.CGpositionSlider.Value = x_cg;
        end

        % Value changed function: CGpositionSlider
        function CGpositionSliderValueChanged(app, event)
            app.TrimPloter;
            app.Trimploter_cg;
        end

        % Value changed function: ForwardCGKnob
        function ForwardCGKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Value changing function: ForwardCGKnob
        function ForwardCGKnobValueChanging(app, event)
            
        end

        % Value changed function: mostforwardCGSwitch
        function mostforwardCGSwitchValueChanged(app, event)
            value = app.mostforwardCGSwitch.Value;
            if value == "Off"
                app.ForwardCGKnob.Visible = "off";
                app.ForwardCGKnobLabel.Visible = "off";
            else
                app.ForwardCGKnob.Visible = "on";
                app.ForwardCGKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changed function: mostaftCGSwitch
        function mostaftCGSwitchValueChanged(app, event)
            value = app.mostaftCGSwitch.Value;
            if value == "Off"
                app.AftCGKnob.Visible = "off";
                app.AftCGKnobLabel.Visible = "off";
            else
                app.AftCGKnob.Visible = "on";
                app.AftCGKnobLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changing function: CGpositionSlider
        function CGpositionSliderValueChanging(app, event)
            
        end

        % Value changed function: AftCGKnob
        function AftCGKnobValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Size changed function: DataPanel
        function DataPanelSizeChanged(app, event)
            %position = app.DataPanel.Position;
            
        end

        % Value changed function: CmzeroEditField
        function CmzeroEditFieldValueChanged(app, event)
            %value = app.CmzeroEditField.Value;
            
        end

        % Value changed function: MaximumAlphaSwitch
        function MaximumAlphaSwitchValueChanged(app, event)
            value = app.MaximumAlphaSwitch.Value;
            if value == "Off"
                app.MaximumAlphaSlider.Visible = "off";
                app.MaximumAlphaSliderLabel.Visible = "off";
            else
                app.MaximumAlphaSlider.Visible = "on";
                app.MaximumAlphaSliderLabel.Visible = "on";
            end
            app.Trimploter_cg;
        end

        % Value changed function: MaximumAlphaSlider
        function MaximumAlphaSliderValueChanged(app, event)
            app.Trimploter_cg;
        end

        % Value changed function: CGSliderSwitch
        function CGSliderSwitchValueChanged(app, event)

            value = app.CGSliderSwitch.Value;
            if value == "Off"
                app.Image.Visible = "off";
                app.CGpositionSlider.Visible = "off";
                app.CGpositionSliderLabel.Visible = "off";
            else
                app.Image.Visible = "on";
                app.CGpositionSlider.Visible = "on";
                app.CGpositionSliderLabel.Visible = "on";
                app.CGpositionSlider.Value = app.XcgEditField.Value;
            end
            app.TrimPloter;
            app.Trimploter_cg;
        end

        % Selection changed function: AircraftsButtonGroup
        function AircraftsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AircraftsButtonGroup.SelectedObject.Text;
            if selectedButton == "Phoenix 50 Seat"
                app.off_all_photo;
                %app.Boeing747.Visible = 'on';
                make_data;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                %app.CliHEditField.Value            = Cl_iH;
                %app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                %app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                app.TrimPloter;
                app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg; 
            else
                app.off_all_photo;
                %app.Boeing747.Visible = 'on';
                make_data76;
                app.ClzeroEditField.Value          = Cl_zero;
                app.CmzeroEditField.Value          = Cm_zero;
                app.ClEditField.Value              = Cl_alpha;
                app.CmEditField.Value              = Cm_alpha;
                %app.CliHEditField.Value            = Cl_iH;
                %app.CmiHEditField.Value            = Cm_iH;
                app.CldeltaelevatorEditField.Value = Cl_delta_elevator;
                app.CmdeltaelevatorEditField.Value = Cm_delta_elevator;
                %app.iHEditField.Value              = iH;
                app.XcgEditField.Value             = x_cg;
                %app.TrimPloter;
                %app.Trimploter_cg;
                app.CGpositionSlider.Value = x_cg;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AircraftsTab = uitab(app.TabGroup);
            app.AircraftsTab.Title = 'Aircrafts';
            app.DataPanel = uipanel(app.AircraftsTab);
            app.DataPanel.Title = 'Data';
            app.DataPanel.SizeChangedFcn = createCallbackFcn(app, @DataPanelSizeChanged, true);
            app.ClzeroEditFieldLabel = uilabel(app.DataPanel);
            app.ClzeroEditFieldLabel.Text = 'Cl zero';
            app.ClzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.ClEditFieldLabel = uilabel(app.DataPanel);
            app.ClEditFieldLabel.Text = 'Cl α';
            app.ClEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmEditFieldLabel = uilabel(app.DataPanel);
            app.CmEditFieldLabel.Text = 'Cm α';
            app.CmEditField = uieditfield(app.DataPanel, 'numeric');
            app.CldeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CldeltaelevatorEditFieldLabel.Text = 'Cl delta elevator';
            app.CldeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmdeltaelevatorEditFieldLabel = uilabel(app.DataPanel);
            app.CmdeltaelevatorEditFieldLabel.Text = 'Cm delta elevator';
            app.CmdeltaelevatorEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditFieldLabel = uilabel(app.DataPanel);
            app.CmzeroEditFieldLabel.Text = 'Cm zero';
            app.CmzeroEditField = uieditfield(app.DataPanel, 'numeric');
            app.CmzeroEditField.ValueChangedFcn = createCallbackFcn(app, @CmzeroEditFieldValueChanged, true);
            app.XcgEditFieldLabel = uilabel(app.DataPanel);
            app.XcgEditFieldLabel.Text = 'X cg';
            app.XcgEditField = uieditfield(app.DataPanel, 'numeric');
            app.Image11 = uiimage(app.AircraftsTab);
            app.Image11.ImageSource = 'logo-en.png';
            app.Image12 = uiimage(app.AircraftsTab);
            app.Image12.ImageSource = 'phlogo.png';
            app.AircraftsButtonGroup = uibuttongroup(app.AircraftsTab);
            app.AircraftsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AircraftsButtonGroupSelectionChanged, true);
            app.AircraftsButtonGroup.Title = 'Aircrafts';
            app.Phoenix50SeatButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Phoenix50SeatButton.Text = 'Phoenix 50 Seat';
            app.Phoenix50SeatButton.Value = true;
            app.Phoenix76SeatButton = uiradiobutton(app.AircraftsButtonGroup);
            app.Phoenix76SeatButton.Text = 'Phoenix 76 Seat';
            app.AirplaneDesignIILabel = uilabel(app.AircraftsTab);
            app.AirplaneDesignIILabel.Text = 'Airplane Design II';
            app.DrBanazadehLabel = uilabel(app.AircraftsTab);
            app.DrBanazadehLabel.Text = 'Dr. Banazadeh';
            app.SourcecodeavailableatLabel = uilabel(app.AircraftsTab);
            app.SourcecodeavailableatLabel.Text = 'Source code available at:';
            app.githubcomalibaniasad1999AirplaneDesignIILabel = uilabel(app.AircraftsTab);
            app.githubcomalibaniasad1999AirplaneDesignIILabel.Text = 'github.com/alibaniasad1999/Airplane-Design-II';
            app.ContactUsLabel = uilabel(app.AircraftsTab);
            app.ContactUsLabel.Text = 'Contact Us:';
            app.alibaniasad1999yahoocomLabel = uilabel(app.AircraftsTab);
            app.alibaniasad1999yahoocomLabel.Text = 'alibaniasad1999@yahoo.com';
            app.Image13 = uiimage(app.AircraftsTab);
            app.Image13.ImageSource = 'IMG_7195.JPG';
            app.TrimDiagramTab = uitab(app.TabGroup);
            app.TrimDiagramTab.Title = 'Trim Diagram';
            app.FigurePanel = uipanel(app.TrimDiagramTab);
            app.FigurePanel.Title = 'Figure';
            app.UIAxes = uiaxes(app.FigurePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2 = uiaxes(app.FigurePanel);
            title(app.UIAxes_2, 'x')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ControlCardsPanel = uipanel(app.TrimDiagramTab);
            app.ControlCardsPanel.Title = 'Control Cards';
            app.Image = uiimage(app.ControlCardsPanel);
            app.Image.ImageSource = 'B747_005.jpg.0797481f913d7a3eaddf1ef973d948ff 2.jpg';
            app.mostaftCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostaftCGSwitchLabel.Text = 'most aft CG';
            app.mostaftCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostaftCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostaftCGSwitchValueChanged, true);
            app.mostforwardCGSwitchLabel = uilabel(app.ControlCardsPanel);
            app.mostforwardCGSwitchLabel.Text = 'most forward CG';
            app.mostforwardCGSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.mostforwardCGSwitch.ValueChangedFcn = createCallbackFcn(app, @mostforwardCGSwitchValueChanged, true);
            app.ForwardCGKnobLabel = uilabel(app.ControlCardsPanel);
            app.ForwardCGKnobLabel.Text = 'Forward CG';
            app.ForwardCGKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.ForwardCGKnob.Limits = [0 0.2];
            app.ForwardCGKnob.ValueChangedFcn = createCallbackFcn(app, @ForwardCGKnobValueChanged, true);
            app.ForwardCGKnob.ValueChangingFcn = createCallbackFcn(app, @ForwardCGKnobValueChanging, true);
            app.AftCGKnobLabel = uilabel(app.ControlCardsPanel);
            app.AftCGKnobLabel.Text = 'Aft CG';
            app.AftCGKnob = uiknob(app.ControlCardsPanel, 'continuous');
            app.AftCGKnob.Limits = [0.8 1];
            app.AftCGKnob.MajorTicks = [0.7 0.75 0.8 0.85 0.9];
            app.AftCGKnob.ValueChangedFcn = createCallbackFcn(app, @AftCGKnobValueChanged, true);
            app.AftCGKnob.Value = 0.85;
            app.CGpositionSliderLabel = uilabel(app.ControlCardsPanel);
            app.CGpositionSliderLabel.Text = 'CG position';
            app.CGpositionSlider = uislider(app.ControlCardsPanel);
            app.CGpositionSlider.Limits = [0 1];
            app.CGpositionSlider.ValueChangedFcn = createCallbackFcn(app, @CGpositionSliderValueChanged, true);
            app.CGpositionSlider.ValueChangingFcn = createCallbackFcn(app, @CGpositionSliderValueChanging, true);
            app.CGpositionSlider.Value = 0.25;
            app.MaximumAlphaSwitchLabel = uilabel(app.ControlCardsPanel);
            app.MaximumAlphaSwitchLabel.Text = 'Maximum Alpha';
            app.MaximumAlphaSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.MaximumAlphaSwitch.ValueChangedFcn = createCallbackFcn(app, @MaximumAlphaSwitchValueChanged, true);
            app.MaximumAlphaSliderLabel = uilabel(app.ControlCardsPanel);
            app.MaximumAlphaSliderLabel.Text = 'Maximum Alpha';
            app.MaximumAlphaSlider = uislider(app.ControlCardsPanel);
            app.MaximumAlphaSlider.Limits = [10 15];
            app.MaximumAlphaSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumAlphaSliderValueChanged, true);
            app.MaximumAlphaSlider.Value = 10;
            app.CGSliderSwitchLabel = uilabel(app.ControlCardsPanel);
            app.CGSliderSwitchLabel.Text = 'CG Slider';
            app.CGSliderSwitch = uiswitch(app.ControlCardsPanel, 'slider');
            app.CGSliderSwitch.ValueChangedFcn = createCallbackFcn(app, @CGSliderSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimDiagramApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end